classdef enterMicroscopyImg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicroscopyUI                 matlab.ui.Figure
        BasicinfoPanel               matlab.ui.container.Panel
        ScalebarumpxEditFieldLabel   matlab.ui.control.Label
        ThicknessumEditFieldLabel    matlab.ui.control.Label
        EExpID                       matlab.ui.control.EditField
        DMicroscope                  matlab.ui.control.DropDown
        EMag                         matlab.ui.control.NumericEditField
        EScale                       matlab.ui.control.NumericEditField
        DDate                        matlab.ui.control.DatePicker
        DHem                         matlab.ui.control.DropDown
        DOri                         matlab.ui.control.DropDown
        OrientationDropDownLabel     matlab.ui.control.Label
        HemisphereDropDownLabel      matlab.ui.control.Label
        SectioncolumnEditFieldLabel  matlab.ui.control.Label
        SectionrowEditFieldLabel     matlab.ui.control.Label
        SlideEditFieldLabel          matlab.ui.control.Label
        MagnificationEditFieldLabel  matlab.ui.control.Label
        AcqdateDatePickerLabel       matlab.ui.control.Label
        MicroscopeDropDownLabel      matlab.ui.control.Label
        ExpIDLabel                   matlab.ui.control.Label
        EThick                       matlab.ui.control.NumericEditField
        ESlide                       matlab.ui.control.NumericEditField
        ESectionRow                  matlab.ui.control.NumericEditField
        ESectionColumn               matlab.ui.control.NumericEditField
        OutputpathEditFieldLabel     matlab.ui.control.Label
        ChannelsPanel                matlab.ui.container.Panel
        SelFluor                     matlab.ui.control.CheckBox
        Color1Panel                  matlab.ui.container.Panel
        EChNr1                       matlab.ui.control.NumericEditField
        DColor1                      matlab.ui.control.DropDown
        EStain1                      matlab.ui.control.EditField
        EFluor1                      matlab.ui.control.EditField
        FluorophoreEditFieldLabel    matlab.ui.control.Label
        ChannelnrEditFieldLabel      matlab.ui.control.Label
        StainEditFieldLabel          matlab.ui.control.Label
        ColorDropDownLabel           matlab.ui.control.Label
        Color2Panel                  matlab.ui.container.Panel
        SelCh2                       matlab.ui.control.CheckBox
        EChNr2                       matlab.ui.control.NumericEditField
        DColor2                      matlab.ui.control.DropDown
        EStain2                      matlab.ui.control.EditField
        EFluor2                      matlab.ui.control.EditField
        FluorophoreEditField_2Label  matlab.ui.control.Label
        ChannelnrEditField_2Label    matlab.ui.control.Label
        StainEditField_2Label        matlab.ui.control.Label
        ColorDropDown_2Label         matlab.ui.control.Label
        Color3Panel                  matlab.ui.container.Panel
        SelCh3                       matlab.ui.control.CheckBox
        EChNr3                       matlab.ui.control.NumericEditField
        DColor3                      matlab.ui.control.DropDown
        EStain3                      matlab.ui.control.EditField
        EFluor3                      matlab.ui.control.EditField
        FluorophoreEditField_3Label  matlab.ui.control.Label
        ChannelnrEditField_3Label    matlab.ui.control.Label
        StainEditField_3Label        matlab.ui.control.Label
        ColorDropDown_3Label         matlab.ui.control.Label
        AreasPanel                   matlab.ui.container.Panel
        Sel17                        matlab.ui.control.CheckBox
        SelPSS                       matlab.ui.control.CheckBox
        SelVis                       matlab.ui.control.CheckBox
        SelLGN                       matlab.ui.control.CheckBox
        SelThal                      matlab.ui.control.CheckBox
        SelS1                        matlab.ui.control.CheckBox
        SelSom                       matlab.ui.control.CheckBox
        BSelImgIn                    matlab.ui.control.Button
        LInputName                   matlab.ui.control.Label
        OutputimageLabel             matlab.ui.control.Label
        EOutpath                     matlab.ui.control.EditField
        BSelOutpath                  matlab.ui.control.Button
        LOutputName                  matlab.ui.control.Label
        BEnter                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        connAccess % connection to Access database
        connSQL    % connection to mySQL database 
        FileInPath %path for input file
        FileInName %name of input file
        FileInExt  %filetype
        FileOutPath %path for output file
    end
    
    methods (Access = private)
        
        function openAccess(app)
            app.connAccess=database('ferretDatabase','','');
            if ~isempty(app.connAccess.Message)
                uialert(app.UIDBEntry,'Cannot open Access Database!','Error')
                return;
            end
        end

        function openSQL(app)
            app.connSQL=database('mscopeDatabase','','');
            if ~isempty(app.connSQL.Message)
                uialert(app.UIDBEntry,'Cannot open MySQL Database!','Error')
                return;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BSelImgIn
        function BSelImgInPushed(app, event)
            %get input image
            [f,p] = uigetfile('*','Select image file');
            if f==0
                return;
            end
            figure(app.MicroscopyUI)
            app.FileInPath=p;
            app.FileInName=f;
            [~,~,app.FileInExt]=fileparts(f);

            app.LInputName.Text=fullfile(p,f);
        end

        % Button pushed function: BSelOutpath
        function BSelOutpathPushed(app, event)
            app.FileOutPath=uigetdir(app.EOutpath.Value);
            figure(app.MicroscopyUI)
            app.EOutpath.Value=app.FileOutPath;
        end

        % Button pushed function: BEnter
        function BEnterPushed(app, event)
            %open databases
            app.openAccess();
            app.openSQL();


            expId=app.EExpID.Value;

            %test whether info still needs to be added to animal table
            sqlquery=['SELECT animalid FROM tblanimal WHERE expId=''',expId,''''];
            dataT=select(app.connSQL,sqlquery);
            
            if isempty(dataT) %need to get data from access

                %first get eartag (providing experiment here)
                sqlquery=['SELECT Eartag FROM tblTissueInfo WHERE [Experiment ID]=''', expId, ''''];

                data=select(app.connAccess,sqlquery);
                if isempty(data)
                    uialert(app.MicroscopyUI,'Exp ID does not exist!','Error');
                    return;
                end

                eartag=data.Eartag{1};

                %now get rest of base info
                sqlquery=['SELECT Sex, DOB, Disposed FROM tblBaseInfo WHERE Eartag=''', eartag, ''''];
                data=select(app.connAccess,sqlquery);

                tableOut=table;
                tableOut.expId={expId};
                tableOut.sex=data.Sex;
                age=between(datetime(data.DOB),datetime(data.Disposed),'days');
                tableOut.age={caldays(age)};
                tableOut.eartag={eartag};

                %rearing
                sqlquery=['SELECT DR,LE,RE FROM tblRearingInfo WHERE Eartag=''', eartag, ''''];
                data=select(app.connAccess,sqlquery);

                if ~isempty(data)
                    if data.DR==1
                        tableOut.rearing={'DR'};
                    elseif data.LE==1 && data.RE==1
                        tableOut.rearing={'BLS'};
                    elseif data.LE==1 && data.RE==0
                        tableOut.rearing={'LLS'};
                    elseif data.LE==0 && data.RE==1
                        tableOut.rearing={'RLS'};
                    end
                else
                    tableOut.rearing={'normal'};
                end

                %write into database
                sqlwrite(app.connSQL,'tblanimal',tableOut);
            end


            %enter into microscopy data base
            %get animal id
            sqlquery=['SELECT animalid FROM tblanimal WHERE expId=''',expId,''''];
            data=select(app.connSQL,sqlquery);
            
            %main table
            tableOut=table;
            %tableOut.filename={outname};
            tableOut.animalid=data.animalid;
            tableOut.microscope={app.DMicroscope.Value};
            tableOut.magnification={app.EMag.Value};
            tableOut.scalebar={app.EScale.Value};
            tableOut.thickness={app.EThick.Value};
            tableOut.orientation={app.DOri.Value};
            tableOut.hemisphere={app.DHem.Value};
            tableOut.slide={app.ESlide.Value};
            tableOut.sectionrow={app.ESectionRow.Value};
            tableOut.sectioncolumn={app.ESectionColumn.Value};
            tableOut.fluorescence={int32(app.SelFluor.Value)};
            tableOut.acqDate={datestr(datenum(app.DDate.Value),29)};
            
            sqlwrite(app.connSQL,'tblmain',tableOut);

            %get imageid to figure out output filename
            %get image id
            %sqlquery=['SELECT imageid FROM tblmain WHERE filename=''',outname,''''];
            sqlquery='SELECT max(imageid) FROM tblmain';
            data=select(app.connSQL,sqlquery);
            imageId=data.max_imageid_;
            imageId=imageId(1);

            outpath=fullfile(app.EOutpath.Value,expId,app.DMicroscope.Value);
            outname=[expId '_' num2str(app.EMag.Value) 'x_' app.EStain1.Value '_' num2str(imageId) app.FileInExt];
            app.LOutputName.Text=fullfile(outpath,outname);
            %enter file name into database
            update(app.connSQL,'tblmain',{'filename'},{outname},['WHERE imageid=''', num2str(imageId), '''']);


            %area table
            tableOut=table;
            tableOut.imageid(1)={imageId};
            count=1;
            if app.Sel17.Value==1
                tableOut.area(1)={'17'};
                count=count+1;
            end
            if app.SelPSS.Value==1
                tableOut.area(count)={'PSS'};
                if count>1
                    tableOut.imageid(count)={imageId};
                end
                count=count+1;
            end


            if app.SelVis.Value==1
                tableOut.area(count)={'OtherVisual'};
                if count>1
                    tableOut.imageid(count)={imageId};
                end
                count=count+1;
            end
            if app.SelLGN.Value==1
                tableOut.area(count)={'LGN'};
                if count>1
                    tableOut.imageid(count)={imageId};
                end
                count=count+1;
            end
            if app.SelThal.Value==1
                tableOut.area(count)={'OtherThalamus'};
                if count>1
                    tableOut.imageid(count)={imageId};
                end
                count=count+1;
            end
            if app.SelS1.Value==1
                tableOut.area(count)={'S1'};
                if count>1
                    tableOut.imageid(count)={imageId};
                end
                count=count+1;
            end
            if app.SelSom.Value==1
                tableOut.area(count)={'OtherSom'};
                if count>1
                    tableOut.imageid(count)={imageId};
                end
            end
            sqlwrite(app.connSQL,'tblarea',tableOut);

            %channels table
            tableOut=table;
            tableOut.imageid(1)={imageId};

            %channel 1 has to be present
            tableOut.channelnr(1)={app.EChNr1.Value};
            tableOut.color(1)={app.DColor1.Value};
            tableOut.stain(1)={app.EStain1.Value};
            if ~isempty(app.EFluor1.Value)
                tableOut.fluorophore(1)={app.EFluor1.Value};
            else
                tableOut.fluorophore(1)={'none'};
            end


            if app.SelCh2.Value==1
                %check: if selected, stain cannot be empty
                if isempty(app.EStain2.Value)
                    uialert(app.MicroscopyUI,'Need to enter stain for channel 2!','Error');
                    return;
                end
                table2=table;
                table2.imageid={imageId};             
                table2.channelnr={app.EChNr2.Value};
                table2.color={app.DColor2.Value};
                table2.stain={app.EStain2.Value};
                if ~isempty(app.EFluor2.Value)
                    table2.fluorophore={app.EFluor2.Value};
                else
                    table2.fluorophore={'none'};
                end
                tableOut=[tableOut;table2];
            end
            

            if app.SelCh3.Value==1
                if isempty(app.EStain3.Value)
                    uialert(app.MicroscopyUI,'Need to enter stain for channel 3!','Error');
                    return;
                end

                table2=table;
                table2.imageid={imageId};
                table2.channelnr={app.EChNr3.Value};
                table2.color={app.DColor3.Value};
                table2.stain={app.EStain3.Value};
                if ~isempty(app.EFluor3.Value)
                    table2.fluorophore={app.EFluor3.Value};
                else
                    table2.fluorophore={'none'};
                end
                tableOut=[tableOut;table2];
            end
            sqlwrite(app.connSQL,'tblchannel',tableOut);

            %save file
            if exist(outpath,'dir')==0
                mkdir(outpath);
            end
            s=copyfile(app.LInputName.Text,app.LOutputName.Text);
            if s==0
                uialert(app.MicroscopyUI,'Could not copy file - fix manually','Error');
            else
                uiconfirm(app.MicroscopyUI,'Copied image file','Confirm done','icon','success');
            end
            
            %finish
            uiconfirm(app.MicroscopyUI,'Database updated.','Confirm done','icon','success');
            close(app.connAccess);
            close(app.connSQL);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MicroscopyUI = uifigure('Visible', 'off');
            app.MicroscopyUI.Name = 'MATLAB App';
            app.BEnter = uibutton(app.MicroscopyUI, 'push');
            app.BEnter.ButtonPushedFcn = createCallbackFcn(app, @BEnterPushed, true);
            app.BEnter.Text = 'Enter';
            app.LOutputName = uilabel(app.MicroscopyUI);
            app.LOutputName.Text = '';
            app.BSelOutpath = uibutton(app.MicroscopyUI, 'push');
            app.BSelOutpath.ButtonPushedFcn = createCallbackFcn(app, @BSelOutpathPushed, true);
            app.BSelOutpath.Text = 'Select';
            app.EOutpath = uieditfield(app.MicroscopyUI, 'text');
            app.EOutpath.Value = 'z:\microscopy';
            app.OutputimageLabel = uilabel(app.MicroscopyUI);
            app.OutputimageLabel.Text = 'Output image';
            app.LInputName = uilabel(app.MicroscopyUI);
            app.LInputName.Text = '';
            app.BSelImgIn = uibutton(app.MicroscopyUI, 'push');
            app.BSelImgIn.ButtonPushedFcn = createCallbackFcn(app, @BSelImgInPushed, true);
            app.BSelImgIn.Text = 'Select image';
            app.AreasPanel = uipanel(app.MicroscopyUI);
            app.AreasPanel.Title = 'Areas';
            app.SelSom = uicheckbox(app.AreasPanel);
            app.SelSom.Text = 'Other somatosensory';
            app.SelS1 = uicheckbox(app.AreasPanel);
            app.SelS1.Text = 'S1';
            app.SelThal = uicheckbox(app.AreasPanel);
            app.SelThal.Text = 'Other thalamus';
            app.SelLGN = uicheckbox(app.AreasPanel);
            app.SelLGN.Text = 'LGN';
            app.SelVis = uicheckbox(app.AreasPanel);
            app.SelVis.Text = 'Other visual areas';
            app.SelPSS = uicheckbox(app.AreasPanel);
            app.SelPSS.Text = 'PSS';
            app.Sel17 = uicheckbox(app.AreasPanel);
            app.Sel17.Text = 'Area 17';
            app.ChannelsPanel = uipanel(app.MicroscopyUI);
            app.ChannelsPanel.Title = 'Channels';
            app.Color3Panel = uipanel(app.ChannelsPanel);
            app.Color3Panel.Title = 'Color 3';
            app.ColorDropDown_3Label = uilabel(app.Color3Panel);
            app.ColorDropDown_3Label.Text = 'Color';
            app.StainEditField_3Label = uilabel(app.Color3Panel);
            app.StainEditField_3Label.Text = 'Stain';
            app.ChannelnrEditField_3Label = uilabel(app.Color3Panel);
            app.ChannelnrEditField_3Label.Text = 'Channel nr.';
            app.FluorophoreEditField_3Label = uilabel(app.Color3Panel);
            app.FluorophoreEditField_3Label.Text = 'Fluorophore';
            app.EFluor3 = uieditfield(app.Color3Panel, 'text');
            app.EStain3 = uieditfield(app.Color3Panel, 'text');
            app.DColor3 = uidropdown(app.Color3Panel);
            app.DColor3.Items = {'black', 'purple', 'brown', 'fl green', 'fl red', 'fl blue', 'fl far-red'};
            app.DColor3.Value = 'brown';
            app.EChNr3 = uieditfield(app.Color3Panel, 'numeric');
            app.EChNr3.Limits = [0 Inf];
            app.EChNr3.Value = 3;
            app.SelCh3 = uicheckbox(app.Color3Panel);
            app.SelCh3.Text = 'Present';
            app.Color2Panel = uipanel(app.ChannelsPanel);
            app.Color2Panel.Title = 'Color 2';
            app.ColorDropDown_2Label = uilabel(app.Color2Panel);
            app.ColorDropDown_2Label.Text = 'Color';
            app.StainEditField_2Label = uilabel(app.Color2Panel);
            app.StainEditField_2Label.Text = 'Stain';
            app.ChannelnrEditField_2Label = uilabel(app.Color2Panel);
            app.ChannelnrEditField_2Label.Text = 'Channel nr.';
            app.FluorophoreEditField_2Label = uilabel(app.Color2Panel);
            app.FluorophoreEditField_2Label.Text = 'Fluorophore';
            app.EFluor2 = uieditfield(app.Color2Panel, 'text');
            app.EStain2 = uieditfield(app.Color2Panel, 'text');
            app.DColor2 = uidropdown(app.Color2Panel);
            app.DColor2.Items = {'black', 'purple', 'brown', 'fl green', 'fl red', 'fl blue', 'fl far-red'};
            app.DColor2.Value = 'purple';
            app.EChNr2 = uieditfield(app.Color2Panel, 'numeric');
            app.EChNr2.Limits = [0 Inf];
            app.EChNr2.Value = 2;
            app.SelCh2 = uicheckbox(app.Color2Panel);
            app.SelCh2.Text = 'Present';
            app.SelCh2.Value = true;
            app.Color1Panel = uipanel(app.ChannelsPanel);
            app.Color1Panel.Title = 'Color 1';
            app.ColorDropDownLabel = uilabel(app.Color1Panel);
            app.ColorDropDownLabel.Text = 'Color';
            app.StainEditFieldLabel = uilabel(app.Color1Panel);
            app.StainEditFieldLabel.Text = 'Stain';
            app.ChannelnrEditFieldLabel = uilabel(app.Color1Panel);
            app.ChannelnrEditFieldLabel.Text = 'Channel nr.';
            app.FluorophoreEditFieldLabel = uilabel(app.Color1Panel);
            app.FluorophoreEditFieldLabel.Text = 'Fluorophore';
            app.EFluor1 = uieditfield(app.Color1Panel, 'text');
            app.EStain1 = uieditfield(app.Color1Panel, 'text');
            app.DColor1 = uidropdown(app.Color1Panel);
            app.DColor1.Items = {'black', 'purple', 'brown', 'fl green', 'fl red', 'fl blue', 'fl far-red'};
            app.DColor1.Value = 'black';
            app.EChNr1 = uieditfield(app.Color1Panel, 'numeric');
            app.EChNr1.Limits = [0 Inf];
            app.EChNr1.Value = 1;
            app.SelFluor = uicheckbox(app.ChannelsPanel);
            app.SelFluor.Text = 'Fluorescence?';
            app.OutputpathEditFieldLabel = uilabel(app.MicroscopyUI);
            app.OutputpathEditFieldLabel.Text = 'Output path';
            app.BasicinfoPanel = uipanel(app.MicroscopyUI);
            app.BasicinfoPanel.Title = 'Basic info';
            app.ESectionColumn = uieditfield(app.BasicinfoPanel, 'numeric');
            app.ESectionColumn.Limits = [1 Inf];
            app.ESectionColumn.Value = 1;
            app.ESectionRow = uieditfield(app.BasicinfoPanel, 'numeric');
            app.ESectionRow.Limits = [1 Inf];
            app.ESectionRow.Value = 1;
            app.ESlide = uieditfield(app.BasicinfoPanel, 'numeric');
            app.ESlide.Limits = [1 Inf];
            app.ESlide.Value = 1;
            app.EThick = uieditfield(app.BasicinfoPanel, 'numeric');
            app.EThick.Limits = [1 Inf];
            app.EThick.Value = 60;
            app.ExpIDLabel = uilabel(app.BasicinfoPanel);
            app.ExpIDLabel.Text = 'Exp ID';
            app.MicroscopeDropDownLabel = uilabel(app.BasicinfoPanel);
            app.MicroscopeDropDownLabel.Text = 'Microscope';
            app.AcqdateDatePickerLabel = uilabel(app.BasicinfoPanel);
            app.AcqdateDatePickerLabel.Text = 'Acq date';
            app.MagnificationEditFieldLabel = uilabel(app.BasicinfoPanel);
            app.MagnificationEditFieldLabel.Text = 'Magnification';
            app.SlideEditFieldLabel = uilabel(app.BasicinfoPanel);
            app.SlideEditFieldLabel.Text = 'Slide';
            app.SectionrowEditFieldLabel = uilabel(app.BasicinfoPanel);
            app.SectionrowEditFieldLabel.Text = 'Section row';
            app.SectioncolumnEditFieldLabel = uilabel(app.BasicinfoPanel);
            app.SectioncolumnEditFieldLabel.Text = 'Section column';
            app.HemisphereDropDownLabel = uilabel(app.BasicinfoPanel);
            app.HemisphereDropDownLabel.Text = 'Hemisphere';
            app.OrientationDropDownLabel = uilabel(app.BasicinfoPanel);
            app.OrientationDropDownLabel.Text = 'Orientation';
            app.DOri = uidropdown(app.BasicinfoPanel);
            app.DOri.Items = {'Sagittal', 'Coronal', 'Horizonal', 'Parallel dura'};
            app.DOri.Value = 'Sagittal';
            app.DHem = uidropdown(app.BasicinfoPanel);
            app.DHem.Items = {'L', 'R'};
            app.DHem.Value = 'L';
            app.DDate = uidatepicker(app.BasicinfoPanel);
            app.EScale = uieditfield(app.BasicinfoPanel, 'numeric');
            app.EMag = uieditfield(app.BasicinfoPanel, 'numeric');
            app.EMag.Limits = [1 Inf];
            app.EMag.Value = 10;
            app.DMicroscope = uidropdown(app.BasicinfoPanel);
            app.DMicroscope.Items = {'Keyence', 'Lab Zeiss', 'Zeiss Confocal', 'unknown'};
            app.DMicroscope.Value = 'Keyence';
            app.EExpID = uieditfield(app.BasicinfoPanel, 'text');
            app.ThicknessumEditFieldLabel = uilabel(app.BasicinfoPanel);
            app.ThicknessumEditFieldLabel.Text = 'Thickness (um)';
            app.ScalebarumpxEditFieldLabel = uilabel(app.BasicinfoPanel);
            app.ScalebarumpxEditFieldLabel.Text = 'Scale bar (um/px)';
            % Show the figure after all components are created
            app.MicroscopyUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = enterMicroscopyImg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicroscopyUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicroscopyUI)
        end
    end
end