classdef physiologyDB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIDBEntry                       matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        GitPath                         matlab.ui.control.EditField
        GitPathLabel                    matlab.ui.control.Label
        BrowseGit                       matlab.ui.control.Button
        AnalyzerPath                    matlab.ui.control.EditField
        BrowseAnalyzer                  matlab.ui.control.Button
        IntanPath                       matlab.ui.control.EditField
        BrowseIntan                     matlab.ui.control.Button
        SpikePath                       matlab.ui.control.EditField
        ProcesseddatapathLabel          matlab.ui.control.Label
        BrowseSpike                     matlab.ui.control.Button
        ProjectPath                     matlab.ui.control.EditField
        ProcesseddatapathLabel_2        matlab.ui.control.Label
        BrowseProject                   matlab.ui.control.Button
        AccessDBEdit                    matlab.ui.control.EditField
        MySqlDBEdit                     matlab.ui.control.EditField
        mySqldatabasenameLabel          matlab.ui.control.Label
        AccessdatabasenameEditFieldLabel  matlab.ui.control.Label
        RawintandatapathLabel           matlab.ui.control.Label
        AnalyzerPathEditFieldLabel      matlab.ui.control.Label
        AccessTest                      matlab.ui.control.Button
        mySqlTest                       matlab.ui.control.Button
        EnterAnimalTab                  matlab.ui.container.Tab
        InitialsEditFieldLabel          matlab.ui.control.Label
        UpdateAccessDBPanel             matlab.ui.container.Panel
        EyeopeningRDatePickerLabel      matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        EOType                          matlab.ui.control.NumericEditField
        TypeEditFieldLabel              matlab.ui.control.Label
        AnimalEOL                       matlab.ui.control.DatePicker
        SeteyeopeningLLabel             matlab.ui.control.Label
        AnimalEOR                       matlab.ui.control.DatePicker
        UpdateEO                        matlab.ui.control.CheckBox
        Label_3                         matlab.ui.control.Label
        RearingStop                     matlab.ui.control.DatePicker
        StopDateDatePickerLabel         matlab.ui.control.Label
        RearingStart                    matlab.ui.control.DatePicker
        StartDateDatePickerLabel        matlab.ui.control.Label
        AnimalRearing                   matlab.ui.control.DropDown
        UpdaterearingDropDownLabel      matlab.ui.control.Label
        UpdateRearing                   matlab.ui.control.CheckBox
        AnimalSex                       matlab.ui.control.DropDown
        UpdatesexLabel                  matlab.ui.control.Label
        UpdateSex                       matlab.ui.control.CheckBox
        Label_2                         matlab.ui.control.Label
        LinkAISheet                     matlab.ui.control.EditField
        PathtoanimalinformationsheetEditFieldLabel  matlab.ui.control.Label
        FindAIButton                    matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Warning                         matlab.ui.control.CheckBox
        EnterAnimalDB                   matlab.ui.control.Button
        ExperimentRoom                  matlab.ui.control.DropDown
        RoomLabel                       matlab.ui.control.Label
        ExperimentDate                  matlab.ui.control.DatePicker
        ExperimentDateLabel             matlab.ui.control.Label
        ExperimentDur                   matlab.ui.control.DropDown
        ExperimentDurDropDownLabel      matlab.ui.control.Label
        ExperimentID                    matlab.ui.control.EditField
        ExperimentIDEditFieldLabel      matlab.ui.control.Label
        Eartag                          matlab.ui.control.EditField
        EartagEditFieldLabel            matlab.ui.control.Label
        Terminal                        matlab.ui.control.CheckBox
        Initials                        matlab.ui.control.EditField
        EnterExperimentsTab             matlab.ui.container.Tab
        GenerateIDFiles                 matlab.ui.control.CheckBox
        GetAnalyzer                     matlab.ui.control.Button
        EditGroup                       matlab.ui.control.Button
        EnterExperimentDB               matlab.ui.control.Button
        TableAnalyzer                   matlab.ui.control.Table
        SelectExpID1                    matlab.ui.control.DropDown
        ExperimentIDDropDownLabel       matlab.ui.control.Label
        UpdateExperimentsTab            matlab.ui.container.Tab
        SelectExpID2                    matlab.ui.control.DropDown
        GetSqlRecords                   matlab.ui.control.Button
        TableExperiments                matlab.ui.control.Table
        AddAnalyzer                     matlab.ui.control.Button
        GenerateIDFiles2                matlab.ui.control.CheckBox
        EditGroupSql                    matlab.ui.control.Button
        UpdateExperimentDB              matlab.ui.control.Button
        UpdatefromID                    matlab.ui.control.Button
        ExperimentIDDropDownLabel_2     matlab.ui.control.Label
        UpdateSortTab                   matlab.ui.container.Tab
        InformationtobeenteredLabel     matlab.ui.control.Label
        ReadSort                        matlab.ui.control.Button
        TableSort                       matlab.ui.control.Table
        EnterSortDB                     matlab.ui.control.Button
        EnterDatasetTab                 matlab.ui.container.Tab
        ReadDataset                     matlab.ui.control.Button
        TableDataset                    matlab.ui.control.Table
        EnterDatasetDB                  matlab.ui.control.Button
        EnterPaperTab                   matlab.ui.container.Tab
        PaperLabel                      matlab.ui.control.EditField
        PaperTitle                      matlab.ui.control.EditField
        PaperAuthors                    matlab.ui.control.EditField
        PaperJournal                    matlab.ui.control.EditField
        PaperYear                       matlab.ui.control.NumericEditField
        YearEditFieldLabel              matlab.ui.control.Label
        JournalEditFieldLabel           matlab.ui.control.Label
        AuthorsLastnamesEditFieldLabel  matlab.ui.control.Label
        TitleEditFieldLabel             matlab.ui.control.Label
        ReadPaperData                   matlab.ui.control.Button
        TablePaper                      matlab.ui.control.Table
        EnterPaperDB                    matlab.ui.control.Button
        CopyFiles                       matlab.ui.control.Button
        LabelEditFieldLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        connAccess % connection to Access database
        connSQL    % connection to mySQL database
        pwdSQL     % optional password for SQL database
        nrExp      % nr of experiments for an animal
        anaTable   % table with analyzer info
        anaTableC  % column numbers in table
        expTable   % table with experiment info
        expTableEdit %keeps track of which fields were edited
        expTableC %column numbers in table
        expTableColNames %column names of table
        expTableColTypes %column types
        AnalyzerEntry %need to update analyzer file?
        sortTable  % spkSort entries
        datasetFile % excel file with project or paper dataset
        projTable %table for project dataset
        paperTable %table for paper dataset
    end

    methods (Access = private)

        function openAccess(app)
            app.connAccess=database(app.AccessDBEdit.Value,'','');
            if ~isempty(app.connAccess.Message)
                uialert(app.UIDBEntry,'Cannot open Access Database!','Error')
                return;
            end
        end

        function openSQL(app)
            app.connSQL=database('ephysDatabase','','');
            if ~isempty(app.connSQL.Message)
                %needs password, most likely - assumption is this is stored as a secret
                %called 'dbPassword'
                passw=getSecret('dbPassword');
                app.connSQL=database('ephysDatabase','nielsenlab',passw);
            end
            
            %app.connSQL=database("ephysDatabase",'nielsenlab','Oregon101');
            if ~isempty(app.connSQL.Message)
                uialert(app.UIDBEntry,'Cannot open MySQL Database!','Error')
                disp(app.connSQL.Message)
                return;
            end
        end

        function [manTypes,manDetails]=setManipType(app)
            %this way we only have to define it once
            manTypes={'NA','Muscimol','ACSF','Training','Cooling','Train+Cool','Cooling down','Warming up','Opto inact','Opto act','Whisker trim'};
            manDetails={'V1','PSS','Bidir gr','Mono bidir gr','Gray','V1 mono bidir gr','PSS mono bidir gr','Static gr','Plaids'};
        end

        function probeNames=setProbeNames(app)
            %to limit probe selection to ones that actually exist
            pnames=dir(fullfile(app.GitPath.Value, 'Spike/probeConfig','probeConfig*.m'));
            
            probeNames=cell(1,length(pnames));
            for p=1:length(pnames)
                ptmp=strsplit(pnames(p).name,'_');
                probeNames{p}=ptmp{2}(1:end-2);
            end
            
        end


        function makeAnaTable(app)
            colNames={'Nr','unitNr','expNr','abort',...
                'Area1','Probe1','penNr1',...
                'Area2','Probe2','penNr2',...
                'priorMFlag','priorManip','duringMFlag','Manip','Details'};
            colTypes={'double','string','string','double',...
                'string','categorical','double',...
                'string','categorical','double',...
                'double','categorical','double','categorical','string'};                       
            
            [manTypes,manDetails]=app.setManipType();
            probeNames=app.setProbeNames();

            app.anaTable=table('Size',[app.nrExp length(colNames)],'VariableTypes',colTypes,'VariableNames',colNames);
            app.anaTable.Manip=categorical(app.anaTable.Manip,manTypes,'Protected',true);
            app.anaTable.priorManip=categorical(app.anaTable.priorManip,manTypes,'Protected',true);
            app.anaTable.Details=categorical(app.anaTable.Details,manDetails);
            app.anaTable.Probe1=categorical(app.anaTable.Probe1,probeNames,'Protected',true);
            app.anaTable.Probe2=categorical(app.anaTable.Probe2,probeNames,'Protected',true);
            app.anaTable.penNr1(1:end)=1;
            app.anaTable.penNr2(1:end)=1;

            app.anaTableC.abort=find(strcmp(app.anaTable.Properties.VariableNames,'abort'));
            app.anaTableC.Area1=find(strcmp(app.anaTable.Properties.VariableNames,'Area1'));
            app.anaTableC.Probe1=find(strcmp(app.anaTable.Properties.VariableNames,'Probe1'));
            app.anaTableC.penNr1=find(strcmp(app.anaTable.Properties.VariableNames,'penNr1'));
            app.anaTableC.Area2=find(strcmp(app.anaTable.Properties.VariableNames,'Area2'));
            app.anaTableC.Probe2=find(strcmp(app.anaTable.Properties.VariableNames,'Probe2'));
            app.anaTableC.penNr2=find(strcmp(app.anaTable.Properties.VariableNames,'penNr2'));
            app.anaTableC.priorMFlag=find(strcmp(app.anaTable.Properties.VariableNames,'priorMFlag'));
            app.anaTableC.priorManip=find(strcmp(app.anaTable.Properties.VariableNames,'priorManip'));
            app.anaTableC.duringMFlag=find(strcmp(app.anaTable.Properties.VariableNames,'duringMFlag'));
            app.anaTableC.Manip=find(strcmp(app.anaTable.Properties.VariableNames,'Manip'));
            app.anaTableC.Details=find(strcmp(app.anaTable.Properties.VariableNames,'Details'));
   
        end

        function makeExpTable(app)
            
            app.expTableColNames={'Nr','unitNr','expNr','abort',...
                'Area1','Probe1','penNr1',...
                'Area2','Probe2','penNr2',...
                'priorMFlag','priorManip','duringMFlag','Manip','Details','RecZ'};
            app.expTableColTypes={'double','string','string','double',...
                'string','categorical','double',...
                'string','categorical','double',...
                'double','categorical','double','categorical','string','double'};


            [manTypes,manDetails]=app.setManipType();
            probeNames=app.setProbeNames();


            app.expTable=table('Size',[app.nrExp length(app.expTableColNames)],'VariableTypes',app.expTableColTypes,'VariableNames',app.expTableColNames);
            app.expTable.Manip=categorical(app.expTable.Manip,manTypes,'Protected',true);
            app.expTable.priorManip=categorical(app.expTable.priorManip,manTypes,'Protected',true);
            app.expTable.Details=categorical(app.expTable.Details,manDetails);
            app.expTable.Probe1=categorical(app.expTable.Probe1,probeNames,'Protected',true);
            app.expTable.Probe2=categorical(app.expTable.Probe2,probeNames,'Protected',true);

            [colTypes2{1:length(app.expTableColNames)}]=deal('double');
            app.expTableEdit=table('Size',[app.nrExp length(app.expTableColNames)],'VariableTypes',colTypes2,'VariableNames',app.expTableColNames);

            %to make life easier later - column numbers
            app.expTableC.abort=find(strcmp(app.expTable.Properties.VariableNames,'abort'));
            app.expTableC.Area1=find(strcmp(app.expTable.Properties.VariableNames,'Area1'));
            app.expTableC.Probe1=find(strcmp(app.expTable.Properties.VariableNames,'Probe1'));
            app.expTableC.penNr1=find(strcmp(app.expTable.Properties.VariableNames,'penNr1'));         
            app.expTableC.Area2=find(strcmp(app.expTable.Properties.VariableNames,'Area2'));
            app.expTableC.Probe2=find(strcmp(app.expTable.Properties.VariableNames,'Probe2'));
            app.expTableC.penNr2=find(strcmp(app.expTable.Properties.VariableNames,'penNr2'));         
            app.expTableC.priorMFlag=find(strcmp(app.expTable.Properties.VariableNames,'priorMFlag'));
            app.expTableC.priorManip=find(strcmp(app.expTable.Properties.VariableNames,'priorManip'));
            app.expTableC.duringMFlag=find(strcmp(app.expTable.Properties.VariableNames,'duringMFlag'));
            app.expTableC.Manip=find(strcmp(app.expTable.Properties.VariableNames,'Manip'));
            app.expTableC.Details=find(strcmp(app.expTable.Properties.VariableNames,'Details'));
            app.expTableC.RecZ=find(strcmp(app.expTable.Properties.VariableNames,'RecZ'));
        end

        function createExpEntry(app)

            tmpTable=table('Size',[1 length(app.expTableColNames)],'VariableTypes',app.expTableColTypes,'VariableNames',app.expTableColNames);
            [manTypes,manDetails]=app.setManipType();
            probeNames=app.setProbeNames();

            tmpTable.Manip=categorical(tmpTable.Manip,manTypes,'Protected',true);
            tmpTable.priorManip=categorical(tmpTable.priorManip,manTypes,'Protected',true);
            tmpTable.Details=categorical(tmpTable.Details,manDetails);
            tmpTable.Probe1=categorical(tmpTable.Probe1,probeNames,'Protected',true);
            tmpTable.Probe2=categorical(tmpTable.Probe2,probeNames,'Protected',true);

            app.expTable=[app.expTable;tmpTable];

            [colTypes2{1:length(app.expTableColNames)}]=deal('double');
            tmpTableEdit=table('Size',[1 length(app.expTableColNames)],'VariableTypes',colTypes2,'VariableNames',app.expTableColNames);
            tmpTableEdit.Nr=1; %we will use this to indicate that a new entry into the db is necessary
            app.expTableEdit=[app.expTableEdit;tmpTableEdit];

        end

        function propTable=makeGroupTable(app,type)
            %generate table for group edits
            if type==1
                propNames={'abort','Area1','Probe1','penNr1',...
                    'Area2','Probe2','penNr2',...
                    'priorMFlag','priorManip','duringMFlag','Manip','Details'};
                propTypes={'double','string','categorical','double',...
                    'string','categorical','double',...
                    'double','categorical','double','categorical','string'};               
            else
                propNames={'abort','Area1','Probe1','penNr1',...
                    'Area2','Probe2','penNr2',...
                    'priorMFlag','priorManip','duringMFlag','Manip','Details','RecZ'};
                propTypes={'double','string','categorical','double',...
                    'string','categorical','double',...
                    'double','categorical','double','categorical','string','double'};
            end
            
            propTable=table('Size',[1 length(propNames)],'VariableTypes',propTypes,'VariableNames',propNames);
            
            [manTypes,manDetails]=app.setManipType();
            propTable.Manip=categorical(propTable.Manip,manTypes,'Protected',true);
            propTable.priorManip=categorical(propTable.priorManip,manTypes,'Protected',true);
            propTable.Details=categorical(propTable.Details,manDetails);

            probeNames=app.setProbeNames();
            propTable.Probe1=categorical(propTable.Probe1,probeNames,'Protected',true);
            propTable.Probe2=categorical(propTable.Probe2,probeNames,'Protected',true);
            
            propTable.abort(1:end)=NaN;
            propTable.penNr1(1:end)=NaN;
            propTable.penNr2(1:end)=NaN;
            propTable.priorMFlag(1:end)=NaN;
            propTable.duringMFlag(1:end)=NaN;
            if type==2
                propTable.RecZ(1:end)=NaN;
            end
        end

        function recprobe = makeProbe(app,type)

            if exist(['probeConfig_' type],'file')

                %run probe function to get info
                probewiring=feval(['probeConfig_' type]);
                %make sure it's organized according to intan channel
                %(issues otherwise)
                probewiring=sortrows(probewiring);
                recprobe.channels = probewiring(:,1);
                recprobe.x = probewiring(:,2);
                recprobe.y = probewiring(:,3);
                recprobe.z = probewiring(:,4);
                recprobe.shaft = probewiring(:,5);

                recprobe.type = type;
            else
                recprobe.channels = NaN;
                recprobe.x = NaN;
                recprobe.y = NaN;
                recprobe.z = NaN;
                recprobe.shaft = NaN;
                recprobe.type = type;
            end
        end


    end

    methods (Access = public)
        
        function getExpInfo(app,ExpFrom,ExpTo,propTable,returnType)
            if ExpFrom~=-1 %-1 = cancel
                %update tables first (in case there were manual edits)
                if returnType==1
                    app.anaTable=app.TableAnalyzer.Data;
                else
                    app.expTable=app.TableExperiments.Data;
                end
                
                diffCell=uistyle('BackgroundColor','yellow');

                %loop through experiments and update table
                for i=ExpFrom:ExpTo

                    for j=1:width(propTable)
                        %test whether we should proceed (needs extra step
                        %because numberic data doesn't show up as missing)
                        updateData=~ismissing(propTable(1,j));
                        if isnumeric(propTable{1,j})
                            updateData=~isnan(propTable{1,j});
                        end

                        if updateData
                            %updated entry - edit different table based on
                            %calling button
                            pName=propTable.Properties.VariableNames{j};
                            pval=propTable{1,j};

                            if returnType==1
                                if ~strcmp(pName,'RecZ')
                                    app.anaTable.(pName)(i)=pval;
                                end
                            else
                                app.expTable.(pName)(i)=pval;
                                app.expTableEdit.(pName)(i)=1;
                                addStyle(app.TableExperiments,diffCell,'cell',[i,app.expTableC.(pName)]);
                            end
                        end
                    end
                end

                if returnType==1
                    app.TableAnalyzer.Data=app.anaTable;
                else
                    app.TableExperiments.Data=app.expTable;
                end
            
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.pwdSQL=''; %set default
            
            if nargin>1 %gitpath provided
                app.GitPath.Value=varargin{1};
            end

        end

        % Button pushed function: BrowseGit
        function BrowseGitButtonPushed(app, event)
            gitpath=uigetdir;
            if gitpath(1)~=0
                app.GitPath.Value=gitpath;
            end
            figure(app.UIDBEntry)
        end

        % Button pushed function: BrowseAnalyzer
        function BrowseAnalyzerPushed(app, event)
            anapath=uigetdir;
            if anapath(1)~=0
                app.AnalyzerPath.Value=anapath;
            end
            figure(app.UIDBEntry)
        end

        % Button pushed function: BrowseIntan
        function BrowseIntanPushed(app, event)
            spikepath=uigetdir;
            if spikepath(1)~=0
                app.IntanPath.Value=spikepath;
            end
            figure(app.UIDBEntry)
        end

        % Button pushed function: BrowseSpike
        function BrowseSpikeButtonPushed(app, event)
            spikepath=uigetdir;
            if spikepath(1)~=0
                app.SpikePath.Value=spikepath;
            end
            figure(app.UIDBEntry)
        end

        % Button pushed function: BrowseProject
        function BrowseProjectButtonPushed(app, event)
            projectpath=uigetdir;
            if projectpath(1)~=0
                app.ProjectPath.Value=projectpath;
            end
            figure(app.UIDBEntry)
        end

        % Button pushed function: AccessTest
        function AccessTestPushed(app, event)
            listDB=listDataSources(); %figure out whether the connector name exists at all
            if any(strcmp(listDB.Name,app.AccessDBEdit.Value))
                app.openAccess();
                if isempty(app.connAccess.Message)
                    uialert(app.UIDBEntry,'Database connection successful!','Message','Icon','success')
                else
                    uialert(app.UIDBEntry,'Cannot open database!','Error')
                end
                close(app.connAccess);
            else
                uialert(app.UIDBEntry,'Wrong database name!','Error')
            end

        end

        % Button pushed function: mySqlTest
        function mySqlTestPushed(app, event)
            listDB=listDataSources(); %figure out whether the connector name exists at all
            if any(strcmp(listDB.Name,app.MySqlDBEdit.Value))
                app.openSQL();
                if isempty(app.connSQL.Message)
                    uialert(app.UIDBEntry,'Database connection successful!','Message','Icon','success')
                else
                    uialert(app.UIDBEntry,'Cannot open database!','Error')
                end
                close(app.connSQL);
            else
                uialert(app.UIDBEntry,'Wrong database name!','Error')
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Title,'Enter experiments')
                %populate dropdown - these are animals that are entered in
                %tblanimal but not yet in tblexperiment
                app.openSQL();
                sqlquery=['SELECT experimentId  FROM ferretphysiology.tblanimal '...
                    'WHERE experimentId NOT IN (SELECT experimentId FROM ferretphysiology.tblexperiment)'];
                sqlExp=select(app.connSQL,sqlquery);
                
                close(app.connSQL);
                if ~isempty(sqlExp)
                    
                    app.SelectExpID1.Items=table2array(sqlExp);
                    if ~isempty(app.ExperimentID.Value)
                        app.SelectExpID1.Value=lower(app.ExperimentID.Value);
                    end
                else
                    uialert(app.UIDBEntry,'No experiments to enter!','Info','Icon','info');
                end
                close(app.connSQL);
            elseif strcmp(selectedTab.Title,'Update experiments')
                app.openSQL();
                sqlquery='SELECT DISTINCT experimentId FROM ferretphysiology.tblexperiment';
                sqlExp=select(app.connSQL,sqlquery);
                app.SelectExpID2.Items=sort(table2array(sqlExp));
                close(app.connSQL);
            end
        end

        % Button pushed function: FindAIButton
        function FindAIButtonPushed(app, event)
            [AIfile,AIPath]=uigetfile;
            if AIfile~=0
                app.LinkAISheet.Value=fullfile(AIPath,AIfile);
            end
        end

        % Value changed function: ExperimentDate
        function ExpDateChanged(app, event)
            value = app.ExperimentDate.Value;
            app.RearingStop.Value=value;
        end

        % Button pushed function: EnterAnimalDB
        function EnterAnimalDBPushed(app, event)
            %progress bar
            wb=uiprogressdlg(app.UIDBEntry,'Title','Entering data','Indeterminate','on');
            
            %open databases
            app.openAccess();
            app.openSQL();

            %%%%%get info from fields
            animalId=app.Eartag.Value;
            expId=lower(app.ExperimentID.Value);
            expDate=app.ExperimentDate.Value;
            terminalExp=app.Terminal.Value;
            expDur=app.ExperimentDur.Value;
            expRoom=app.ExperimentRoom.Value;
            initials=app.Initials.Value;

            %make sure things were actually entered
            if isempty(animalId) || isempty(expId) || isnat(expDate)
                uialert(app.UIDBEntry,'Enter information in all fields!','Error');
                return;
            end

            %convert date into correct format for database
            expDate=datestr(datenum(expDate),29);

            if app.UpdateSex.Value==1
                animalSex=app.AnimalSex.Value;
            end

            if app.UpdateRearing.Value==1
                rearCond=app.AnimalRearing.Value;
                rearStart=app.RearingStart.Value;
                rearStop=app.RearingStop.Value;

                %convert dates
                if ~isnat(rearStart)
                    rearStart=datestr(datenum(rearStart),29);
                else
                    rearStart='';
                end
                if ~isnat(rearStop)
                    rearStop=datestr(datenum(rearStop),29);
                else
                    rearStop='';
                end
            end

            if app.UpdateEO.Value==1
                eoType=app.EOType.Value;
                if ~isnat(app.AnimalEOL.Value) %date has been set
                    animalEOL=datestr(datenum(app.AnimalEOL.Value),29);
                end
                if ~isnat(app.AnimalEOR.Value) %date has been set
                    animalEOR=datestr(datenum(app.AnimalEOR.Value),29);
                end
                if isnat(app.AnimalEOL.Value) && isnat(app.AnimalEOR.Value)
                    app.UpdateEO.Value=0;
                end
            end

            %%%%%update access database
            %first check whether animal exists in database
            sqlquery=['SELECT Eartag, Disposed FROM tblBaseInfo WHERE Eartag=''', animalId, ''''];
            data=select(app.connAccess,sqlquery);
            if isempty(data)
                uialert(app.UIDBEntry,'Wrong eartag!','Error');
                return;
            end

            %also check whether for that animal an experiment has already
            %been entered and ask whether to proceed
            sqlquery=['SELECT Eartag FROM tblExperimentInfo WHERE Eartag=''', animalId, ''''];
            dTmp=select(app.connAccess,sqlquery);
            if ~isempty(dTmp)
                sel=uiconfirm(app.UIDBEntry,'Animal already has an Experiment ID in Access. Proceed?','Question','Options',{'Yes','No'});
                if strcmp(sel,'No')
                    return;
                end
            end

            
            %update base info - only if disposed data not previously set
            if terminalExp
                if ~isempty(data.Disposed{1})
                    uialert(app.UIDBEntry,'Disposed date already set in Access, did not update basic info','Warning');
                else
                    %dealing with matlab bug - doesn't like updating chars
                    %this way, but dates work
                    update(app.connAccess,'tblBaseInfo',{'Disposed'},{expDate},['WHERE Eartag=''', animalId, '''']);
                    sqlquery=['UPDATE tblBaseInfo SET Reason=''Terminal exp.'',Cage='''' WHERE Eartag=''' animalId ''''];
                    exec(app.connAccess,sqlquery);

                    if exist(app.LinkAISheet.Value,'file')==2
                        sqlquery=['UPDATE tblBaseInfo SET [Animal record]=''' app.LinkAISheet.Value ''' WHERE Eartag=''' animalId ''''];
                        exec(app.connAccess,sqlquery);
                    end
                end
            end

            %update sex if selected
            if app.UpdateSex.Value==1
                sqlquery=['UPDATE tblBaseInfo SET Sex=''' animalSex ''' WHERE Eartag=''' animalId ''''];
                exec(app.connAccess,sqlquery);
            end

            %update rearing condition if selected
            if app.UpdateRearing.Value==1
                %check whether rearing info has already been
                %entered
                sqlquery=['SELECT DR,LE,RE FROM tblRearingInfo WHERE Eartag=''', animalId, ''''];
                rearingData=select(app.connAccess,sqlquery);
                if ~isempty(rearingData)
                    %if yes, update
                    switch rearCond
                        case 'DR'
                            update(app.connAccess,'tblRearingInfo','DR',1,['WHERE Eartag=''', animalId, '''']);
                            if ~isempty(rearStart)
                                update(app.connAccess,'tblRearingInfo','[DR start]',{rearStart},['WHERE Eartag=''', animalId, '''']);
                            end
                            if ~isempty(rearStop)
                                update(app.connAccess,'tblRearingInfo','[DR stop]',{rearStop},['WHERE Eartag=''', animalId, '''']);
                            end
                        case 'BLS'
                            update(app.connAccess,'tblRearingInfo','LE',1,['WHERE Eartag=''', animalId, '''']);
                            update(app.connAccess,'tblRearingInfo','RE',1,['WHERE Eartag=''', animalId, '''']);
                            if ~isempty(rearStart)
                                update(app.connAccess,'tblRearingInfo','[LE start]',{rearStart},['WHERE Eartag=''', animalId, '''']);
                                update(app.connAccess,'tblRearingInfo','[RE start]',{rearStart},['WHERE Eartag=''', animalId, '''']);
                            end
                            if ~isempty(rearStop)
                                update(app.connAccess,'tblRearingInfo','[LE stop]',{rearStop},['WHERE Eartag=''', animalId, '''']);
                                update(app.connAccess,'tblRearingInfo','[RE stop]',{rearStop},['WHERE Eartag=''', animalId, '''']);
                            end
                        case 'LLS'
                            update(app.connAccess,'tblRearingInfo','LE',1,['WHERE Eartag=''', animalId, '''']);
                            if ~isempty(rearStart)
                                update(app.connAccess,'tblRearingInfo','[LE start]',{rearStart},['WHERE Eartag=''', animalId, '''']);
                            end
                            if ~isempty(rearStop)
                                update(app.connAccess,'tblRearingInfo','[LE stop]',{rearStop},['WHERE Eartag=''', animalId, '''']);
                            end
                        case 'RLS'
                            update(app.connAccess,'tblRearingInfo','RE',1,['WHERE Eartag=''', animalId, '''']);
                            if ~isempty(rearStart)
                                update(app.connAccess,'tblRearingInfo','[RE start]',{rearStart},['WHERE Eartag=''', animalId, '''']);
                            end
                            if ~isempty(rearStop)
                                update(app.connAccess,'tblRearingInfo','[RE stop]',{rearStop},['WHERE Eartag=''', animalId, '''']);
                            end
                    end
                else
                    %if no, generate new
                    if isempty(rearStart) || isempty(rearStop)
                        uialert(app.UIDBEntry,'Need rearing dates for new entry!','Error');
                        return;
                    end
                    switch rearCond
                        case 'DR'
                            rCnames={'Eartag','DR','[DR start]','[DR stop]'};
                            rCval={animalId,true,rearStart,rearStop};
                        case 'BLS'
                            rCnames={'Eartag','LE','[LE start]','[LE stop]','RE','[RE start]','[RE stop]'};
                            rCval={animalId,true,rearStart,rearStop,true,rearStart,rearStop};
                        case 'LLS'
                            rCnames={'Eartag','LE','[LE start]','[LE stop]'};
                            rCval={animalId,true,rearStart,rearStop};
                        case 'RLS'
                            rCnames={'Eartag','RE','[RE start]','[RE stop]'};
                            rCval={animalId,true,rearStart,rearStop};
                    end
                    rtab=cell2table(rCval);
                    rtab.Properties.VariableNames=rCnames;
                    sqlwrite(app.connAccess,'tblRearingInfo',rtab);
                end
            else %may still have to enter stop date in rearing condition (otherwise error in code below)
                if terminalExp
                    sqlquery=['SELECT DR,LE,RE FROM tblRearingInfo WHERE Eartag=''', animalId, ''''];
                    rearingData=select(app.connAccess,sqlquery);
                    if ~isempty(rearingData)
                        if rearingData.DR==1
                            update(app.connAccess,'tblRearingInfo','[DR stop]',{expDate},['WHERE Eartag=''', animalId, '''']);
                        end
                        if rearingData.LE==1
                            update(app.connAccess,'tblRearingInfo','[LE stop]',{expDate},['WHERE Eartag=''', animalId, '''']);
                        end
                        if rearingData.RE==1
                            update(app.connAccess,'tblRearingInfo','[RE stop]',{expDate},['WHERE Eartag=''', animalId, '''']);
                        end
                    end
                end
            end

            if app.UpdateEO.Value==1
                update(app.connAccess,'tblBaseInfo','[Eyes open L]',{animalEOL},['WHERE Eartag=''', animalId, '''']);
                update(app.connAccess,'tblBaseInfo','[Eyes open R]',{animalEOR},['WHERE Eartag=''', animalId, '''']);
                update(app.connAccess,'tblBaseInfo','EOType',{eoType},['WHERE Eartag=''', animalId, ''''])
            end

            %update tblexperimentInfo
            %first check whether this has happened already:
            sqlquery=['SELECT [Experiment ID] FROM tblExperimentInfo WHERE [Experiment ID]=''', expId, ''''];
            data=select(app.connAccess,sqlquery);
            if ~isempty(data)
                uialert(app.UIDBEntry,'Experiment ID already entered in Access, did not update experiments','Warning');
            else
 
                %construct table with info and upate tblExperimentInfo
                eTnames={'Eartag','[Experiment ID]','[Experiment date]','Experiment','Room','Duration','Initials'};
                eTval={animalId,expId,expDate,'Physiology',expRoom,expDur,initials};
                etab=cell2table(eTval);
                etab.Properties.VariableNames=eTnames;
                sqlwrite(app.connAccess,'tblExperimentInfo',etab);
            end

            %%%%%update mysql
            %now enter animal in mysql database - this only updates
            %tblanimal; the rest gets updated in separate tabs

            %two options - new entry, or update animal info
            %if there already is an entry with the same experiment id on
            %the same day, then only proceed to update animal information
            %like rearing and eye opening, rest should remain the same to
            %prevent inconsistencies


            %check that the experiment has not been entered already
            sqlquery=['SELECT animalKey FROM tblanimal WHERE experimentId=''', expId, ''' AND expDate=''',expDate, ''''];
            data=select(app.connSQL,sqlquery);

            if isempty(data)
                %recording session not yet entered
                %add as much of the basic info as available - access has
                %been updated at this point, so ok to pull from there
                sqlquery=['SELECT Sex, DOB, [Eyes open L],[Eyes open R],EOType FROM tblBaseInfo WHERE Eartag=''', animalId, ''''];
                baseData=select(app.connAccess,sqlquery);


                %transform into table for saving
                tableOut=table;
                tableOut.eartag={animalId};
                tableOut.experimentId={expId};
                tableOut.expDate={expDate};
                tableOut.problem={int32(app.Warning.Value)};

                age=between(datetime(baseData.DOB),datetime(expDate),'days');
                tableOut.age={caldays(age)};
                tableOut.sex=baseData.Sex;

                ageEyesOpenL=between(datetime(baseData.DOB),datetime(baseData.EyesOpenL),'days');
                if ~isnan(ageEyesOpenL)
                    tableOut.ageEyesOpenL={caldays(ageEyesOpenL)};
                end
                ageEyesOpenR=between(datetime(baseData.DOB),datetime(baseData.EyesOpenR),'days');
                if ~isnan(ageEyesOpenR)
                    tableOut.ageEyesOpenR={caldays(ageEyesOpenR)};
                end
                if ~isnan(ageEyesOpenL) || ~isnan(ageEyesOpenR)
                    tableOut.eyeOpenType={baseData.EOType};
                end

                %rearing condition from tblRearingInfo
                sqlquery=['SELECT DR, [DR start],[DR stop],LE,[LE start],[LE stop],RE,[RE start],[RE stop]  ' ...
                    'FROM tblRearingInfo WHERE Eartag=''', animalId, ''''];
                rearingData=select(app.connAccess,sqlquery);

                %assumption: either DR or lid sutures, not both
                if ~isempty(rearingData)
                    if rearingData.DR==1
                        tableOut.rearingChange={1};
                        tableOut.rearingCond={'DR'};

                        ageStart=between(datetime(baseData.DOB),datetime(rearingData.DRStart),'days');
                        tableOut.rearingStartAge={caldays(ageStart)};

                        ageStop=between(datetime(baseData.DOB),datetime(rearingData.DRStop),'days');
                        tableOut.rearingStopAge={caldays(ageStop)};
                    end
                    if rearingData.LE==1 && rearingData.RE==1
                        tableOut.rearingChange={1};
                        tableOut.rearingCond={'BLS'};

                        dateStart=min(datetime(rearingData.LEStart),datetime(rearingData.REStart));
                        ageStart=between(datetime(baseData.DOB),dateStart,'days');
                        tableOut.rearingStartAge={caldays(ageStart)};

                        dateStop=min(datetime(rearingData.LEStop),datetime(rearingData.REStop));
                        ageStop=between(datetime(baseData.DOB),dateStop,'days');
                        tableOut.rearingStopAge={caldays(ageStop)};
                    elseif rearingData.LE==1 && rearingData.RE==0
                        tableOut.rearingChange={1};
                        tableOut.rearingCond={'LLS'};

                        ageStart=between(datetime(baseData.DOB),datetime(rearingData.LEStart),'days');
                        tableOut.rearingStartAge={caldays(ageStart)};

                        ageStop=between(datetime(baseData.DOB),datetime(rearingData.LEStop),'days');
                        tableOut.rearingStopAge={caldays(ageStop)};

                    elseif rearingData.LE==0 && rearingData.RE==1
                        tableOut.rearingChange={1};
                        tableOut.rearingCond={'RLS'};

                        ageStart=between(datetime(baseData.DOB),datetime(rearingData.REStart),'days');
                        tableOut.rearingStartAge={caldays(ageStart)};

                        ageStop=between(datetime(baseData.DOB),datetime(rearingData.REStop),'days');
                        tableOut.rearingStopAge={caldays(ageStop)};
                    end
                else
                    tableOut.rearingChange={0};
                    tableOut.rearingCond={'normal'};
                end

                sqlwrite(app.connSQL,'tblanimal',tableOut);


            else
                %experiment has been entered, so do not update basic stuff,
                %but update animal info if selected
                uialert(app.UIDBEntry,'Recoding session already entered in MySQL, only updating other info as selected','Warning');

                %update sex if selected
                if app.UpdateSex.Value==1
                    update(app.connSQL,'tblanimal','sex',{animalSex},['WHERE animalKey=''', num2str(data.animalKey), '''']);
                end

                %update rearing condition if selected
                if app.UpdateRearing.Value==1 %these are all 'not-normal' conditions
                    sqlquery=['SELECT DOB FROM tblBaseInfo WHERE Eartag=''', animalId, ''''];
                    baseData=select(app.connAccess,sqlquery);

                    %rearing condition from tblRearingInfo
                    sqlquery=['SELECT DR, [DR start],[DR stop],LE,[LE start],[LE stop],RE,[RE start],[RE stop]  ' ...
                        'FROM tblRearingInfo WHERE Eartag=''', animalId, ''''];
                    rearingData=select(app.connAccess,sqlquery);

                    update(app.connSQL,'tblanimal','rearingChange',{1},['WHERE animalKey=''', num2str(data.animalKey), '''']);

                    switch rearCond %just for easier writing
                        case 'DR'
                            update(app.connSQL,'tblanimal','rearingCond',{'DR'},['WHERE animalKey=''', num2str(data.animalKey), '''']);

                            ageStart=between(datetime(baseData.DOB),datetime(rearingData.DRStart),'days');
                            ageStop=between(datetime(baseData.DOB),datetime(rearingData.DRStop),'days');

                            update(app.connSQL,'tblanimal','rearingStartAge',{caldays(ageStart)},['WHERE animalKey=''', num2str(data.animalKey), '''']);
                            update(app.connSQL,'tblanimal','rearingStopAge',{caldays(ageStop)},['WHERE animalKey=''', num2str(data.animalKey), '''']);

                        case 'BLS'
                            update(app.connSQL,'tblanimal','rearingCond',{'BLS'},['WHERE animalKey=''', num2str(data.animalKey), '''']);

                            dateStart=min(datetime(rearingData.LEStart),datetime(rearingData.REStart));
                            ageStart=between(datetime(baseData.DOB),dateStart,'days');
                            dateStop=min(datetime(rearingData.LEStop),datetime(rearingData.REStop));
                            ageStop=between(datetime(baseData.DOB),dateStop,'days');

                            update(app.connSQL,'tblanimal','rearingStartAge',{caldays(ageStart)},['WHERE animalKey=''', num2str(data.animalKey), '''']);
                            update(app.connSQL,'tblanimal','rearingStopAge',{caldays(ageStop)},['WHERE animalKey=''', num2str(data.animalKey), '''']);

                        case 'LLS'
                            update(app.connSQL,'tblanimal','rearingCond',{'LLS'},['WHERE animalKey=''', num2str(data.animalKey), '''']);

                            ageStart=between(datetime(baseData.DOB),datetime(rearingData.LEStart),'days');
                            ageStop=between(datetime(baseData.DOB),datetime(rearingData.LEStop),'days');

                            update(app.connSQL,'tblanimal','rearingStartAge',{caldays(ageStart)},['WHERE animalKey=''', num2str(data.animalKey), '''']);
                            update(app.connSQL,'tblanimal','rearingStopAge',{caldays(ageStop)},['WHERE animalKey=''', num2str(data.animalKey), '''']);

                        case 'RLS'
                            update(app.connSQL,'tblanimal','rearingCond',{'RLS'},['WHERE animalKey=''', num2str(data.animalKey), '''']);

                            ageStart=between(datetime(baseData.DOB),datetime(rearingData.REStart),'days');
                            ageStop=between(datetime(baseData.DOB),datetime(rearingData.REStop),'days');

                            update(app.connSQL,'tblanimal','rearingStartAge',{caldays(ageStart)},['WHERE animalKey=''', num2str(data.animalKey), '''']);
                            update(app.connSQL,'tblanimal','rearingStopAge',{caldays(ageStop)},['WHERE animalKey=''', num2str(data.animalKey), '''']);
                    end
                end

                if app.UpdateEO.Value==1
                    sqlquery=['SELECT Sex, DOB, [Eyes open L],[Eyes open R], EOType FROM tblBaseInfo WHERE Eartag=''', animalId, ''''];
                    baseData=select(app.connAccess,sqlquery);
                    ageEyesOpenL=between(datetime(baseData.DOB),datetime(baseData.EyesOpenL),'days');
                    ageEyesOpenR=between(datetime(baseData.DOB),datetime(baseData.EyesOpenR),'days');
                    if ~isnan(ageEyesOpenL)
                        update(app.connSQL,'tblanimal','ageEyesOpenL',{caldays(ageEyesOpenL)},['WHERE animalKey=''', num2str(data.animalKey), '''']);
                    end
                    if ~isnan(ageEyesOpenR)
                        update(app.connSQL,'tblanimal','ageEyesOpenR',{caldays(ageEyesOpenR)},['WHERE animalKey=''', num2str(data.animalKey), '''']);
                    end
                    disp(baseData.EOType)
                    update(app.connSQL,'tblanimal','eyeOpenType',{double(baseData.EOType)},['WHERE animalKey=''', num2str(data.animalKey), '''']);
                end

            end

            close(wb);

            uiconfirm(app.UIDBEntry,'Access and MySQL database updated.','Confirm done','icon','success');
            close(app.connAccess);
            close(app.connSQL);
        end

        % Button pushed function: GetAnalyzer
        function GetAnalyzerPushed(app, event)
            %get analyzer files for a selected experiment
            if isempty(app.SelectExpID1.Value)
                return;
            end
            animal=app.SelectExpID1.Value;
            fpath=fullfile(app.AnalyzerPath.Value,animal ,[animal '*.analyzer']);
            anafiles=dir(fpath);
            app.nrExp=length(anafiles);

            %make summary table
            app.makeAnaTable();

            for i=1:app.nrExp

                [~,ananame,~]=fileparts(anafiles(i).name);
                anaparts=strsplit(ananame,'_');
                anaunit=anaparts{2}(2:4);
                anaexp=anaparts{3}(1:3);

                app.anaTable(i,1)={i};
                app.anaTable(i,2)={anaunit};
                app.anaTable(i,3)={anaexp};

                %load analyzer file to get abort info
                load(fullfile(anafiles(i).folder,anafiles(i).name),'-mat');
                if exist('abortFlag','var')
                    app.anaTable(i,app.anaTableC.abort)={abortFlag};
                end


                app.anaTable(i,app.anaTableC.priorMFlag)={0}; %manipulation; this is a new experiment, so we don't know anything yet
                app.anaTable(i,app.anaTableC.duringMFlag)={0}; %manipulation; this is a new experiment, so we don't know anything yet

                %find info from ID files should they exist
                idName=fullfile(app.SpikePath.Value,animal,ananame,[ananame '_id.mat']);
                if exist(idName,'file')~=0
                    id=load(idName);
                    app.anaTable(i,app.anaTableC.Area1)={id.id.probes(1).area};
                    app.anaTable(i,app.anaTableC.Probe1)={id.id.probes(1).type};
                    if length(id.id.probes)==2
                        if ~isempty(id.id.probes(2).area)
                            app.anaTable(i,app.anaTableC.Area2)={id.id.probes(2).area};
                        end
                        if ~isempty(id.id.probes(2).type)
                            app.anaTable(i,app.anaTableC.Probe2)={id.id.probes(2).type};
                        end
                    end
                end


            end
            app.TableAnalyzer.Data=app.anaTable;
            %set table properties
            app.TableAnalyzer.ColumnName=app.anaTable.Properties.VariableNames;
            app.TableAnalyzer.ColumnWidth={20,50,50,'auto'};
            app.TableAnalyzer.ColumnEditable=true(1,width(app.anaTable));
            app.TableAnalyzer.ColumnEditable(1:3)=false;

            app.EditGroup.Enable=true;
            app.EnterExperimentDB.Enable=true;
        end

        % Button pushed function: EditGroup
        function EditGroupPushed(app, event)
            %open GUI to update multiple files at once
            propTable=app.makeGroupTable(1);
            settingsHandle=updateExpInfo(app,app.nrExp,propTable,1);
            waitfor(settingsHandle);
            figure(app.UIDBEntry)
        end

        % Button pushed function: EnterExperimentDB
        function EnterExperimentDBPushed(app, event)
            %set path as necessary
            addpath([app.GitPath.Value '/Spike/probeConfig/'])
            addpath([app.GitPath.Value '/analyzer/AnalyzerUtils/'])

            %waitbar
            wb=uiprogressdlg(app.UIDBEntry,'Title','Please wait','Message','Updating database');


            %open connection to database
            app.openSQL();

            %update table
            app.anaTable=app.TableAnalyzer.Data;

            %add information from analyzer files and manual edits to db
            expId=app.SelectExpID1.Value;
            fpath=fullfile(app.AnalyzerPath.Value,expId ,[expId '*.analyzer']);
            anafiles=dir(fpath);

            for a=1:length(anafiles)

                wb.Value=a/length(anafiles);

                if ismissing(app.anaTable.Area1(a)) || ismissing(app.anaTable.Probe1(a))
                    uialert(app.UIDBEntry,'Need to enter at least Area 1 & Probe 1','Error');
                    close(wb);
                    return;
                end

                %go through all analyzer files and extract basic information
                %initialize table
                tableExp=table;

                [~,ananame,~]=fileparts(anafiles(a).name);
                anaparts=strsplit(ananame,'_');
                anaunit=anaparts{2}(2:4);
                anaexp=anaparts{3}(1:3);

                %animal, unit, experiment
                tableExp.experimentId={expId};
                tableExp.unitNr={anaunit};
                tableExp.experimentNr={anaexp};

                %now load analyzer file
                load(fullfile(anafiles(a).folder,anafiles(a).name),'-mat');

    
                %module, rep, randomization, date
                tableExp.module={Analyzer.modID};
                tableExp.reps={Analyzer.L.reps};
                tableExp.trialrand={int32(Analyzer.L.rand)};

                %looper variables - first full format
                nLoop=length(Analyzer.L.param);
                for j=1:nLoop
                    tableExp=addvars(tableExp,Analyzer.L.param{j}(1),Analyzer.L.param{j}(2),{int32(Analyzer.L.param{j}{3})},...
                        'NewVariableNames',{['looperName' num2str(j)],['looperVal' num2str(j)],['looperBlock' num2str(j)]});
                end
                %combination of variable and parameter
                [domains,domval,~]=getdomainvalue(Analyzer);
                for j=1:length(domains)
                    nrCond=length(unique(domval(:,j)));
                    tableExp=addvars(tableExp,{[domains{j} num2str(nrCond)]},'NewVariableNames',['looperNameCond' num2str(j)]);
                end

                %abort
                tableExp.abort={double(app.anaTable.abort(a))};

                %phys data exists?
                physName=fullfile(app.IntanPath.Value,expId,ananame,[ananame '_info.rhd']);
                if exist(physName,'file')~=0
                    tableExp.recExists={1};
                else
                    tableExp.recExists={0};
                end

                %nr recording sites
                if ismissing(app.anaTable(a,app.anaTableC.Area2)) %no second recording area entered
                    tableExp.Nsites={1};
                else
                    tableExp.Nsites={2};
                end
                              
                %manipulation?
                tableExp.priorMFlag={double(app.anaTable.priorMFlag(a))};
                tableExp.duringMFlag={double(app.anaTable.duringMFlag(a))};
               

                %write tableExp
                sqlwrite(app.connSQL,'tblexperiment',tableExp);

                %need to get autogenerated key for experiment
                selectquery=['SELECT experimentKey FROM tblexperiment ' ...
                    'WHERE experimentId="' expId ...
                    '" AND unitNr="' anaunit ...
                    '" AND experimentNr="' anaexp '"'];
                data=select(app.connSQL,selectquery);
                expkey=double(data.experimentKey);

                %generate and write recording table - one entry per probe
                for j=1:tableExp.Nsites{1}
                    tableRec=table;
                    tableRec.experimentKey={expkey};
                    tableRec.isBlackrock={0};
                    tableRec.recSite={char(app.anaTable{a,app.anaTableC.(['Area' num2str(j)])})};
                    tableRec.recProbe={char(app.anaTable{a,app.anaTableC.(['Probe' num2str(j)])})};
                    tableRec.spikeSorted={0};
                    tableRec.NSingleUnit={0};
                    tableRec.NMultiUnit={0};
                    tableRec.oldSpikeFormat={0};
                    tableRec.probeId={j};
                    tableExp.penNr={app.anaTable(a,app.anaTableC.(['penNr' num2str(j)]))};

                    sqlwrite(app.connSQL,'tblrecording',tableRec);
                end


                %append entries from table - manipulation table (only if
                %there is something to enter)
                tableMan=table;
                if app.anaTable.priorMFlag(a)==1 || app.anaTable.duringMFlag(a)==1
                    tableMan.experimentKey={expkey};
                    tableMan.manipDescr={char(app.anaTable.Manip(a))};
                    if ~ismissing(app.anaTable.Details(a))
                        tableMan.manipDetail={char(app.anaTable.Details(a))};
                    end
                    tableMan.priorDescr={char(app.anaTable.priorManip(a))};
                    sqlwrite(app.connSQL,'tblmanipulation',tableMan);
                end

                %finally, also add parameters (only add the ones that are
                %marked for db)
                ptable={};
                ptable{1,1}='experimentKey';
                ptable{1,2}='pname';
                ptable{1,3}='pval';
                pcount=2;
                for p=1:length(Analyzer.P.param)
                    if Analyzer.P.param{p}{4}==1
                        ptable{pcount,1}=expkey;
                        ptable{pcount,2}=Analyzer.P.param{p}{1};
                        ptable{pcount,3}=num2str(Analyzer.P.param{p}{3});
                        pcount=pcount+1;
                    end
                end
                if pcount>2
                    tableParam=cell2table(ptable(2:end,:));
                    tableParam.Properties.VariableNames=ptable(1,:);

                    sqlwrite(app.connSQL,'tblparams',tableParam);
                end

                %generate ID file if selected and not already generated
                if app.GenerateIDFiles.Value
                    idName=fullfile(app.SpikePath.Value,expId,ananame,[ananame '_id.mat']);

                    if exist(idName,'file')==0
                        id=struct;
                        for j=1:tableExp.Nsites{1}
                            ptype=char(app.anaTable{a,app.anaTableC.(['Probe' num2str(j)])});
                            probeTmp=app.makeProbe(ptype);
                            probeTmp.area=char(app.anaTable{a,app.anaTableC.(['Area' num2str(j)])});
                            probeTmp.type=ptype;
                            probeTmp.nChannels=length(probeTmp.channels);

                            id.probes(j)=probeTmp;
                        end
                        if exist(fullfile(app.SpikePath.Value,expId,ananame),'dir')==0
                            mkdir(fullfile(app.SpikePath.Value,expId,ananame));
                        end
                        id.isBR=0;
                        id.exptId=ananame;
                        save(idName,'id')
                    end
                end

                %clean up
                clear tableExp tableRec tableMan tableParam Analyzer;

            end %for a
            close(app.connSQL);
            close(wb);
            uiconfirm(app.UIDBEntry,'MySQL database updated.','Confirm done','icon','success');
            app.TableAnalyzer.Data=table;
            app.SelectExpID1.Items={''};
            app.EditGroup.Enable=0;
            app.EnterExperimentDB.Enable=0;
            clear app.anaTable;

        end

        % Value changed function: SelectExpID2
        function SelectExpID2ValueChanged(app, event)
            %new animal selected - remove everything until new data are
            %pulled from database
            app.TableExperiments.Data=table;
            removeStyle(app.TableExperiments);

            app.EditGroupSql.Enable=0;
            app.UpdatefromID.Enable=0;
            app.AddAnalyzer.Enable=0;
            app.AnalyzerEntry=0;
            clear app.expTable app.expTableEdit;
        end

        % Button pushed function: GetSqlRecords
        function GetSqlRecordsPushed(app, event)
            %get data for animal from mysql
            %open connection to database
            app.openSQL();

            %first get info from tblexperiment
            expId=app.SelectExpID2.Value;

            selectquery=['SELECT experimentKey,unitNr,experimentNr,abort,priorMFlag,duringMFlag,recExists FROM tblexperiment ' ...
                'WHERE experimentId="' expId '"'];
            data=select(app.connSQL,selectquery);

            %make table
            app.nrExp=height(data);
            app.makeExpTable();

            %populate
            app.expTable.unitNr=data.unitNr;
            app.expTable.expNr=data.experimentNr;
            app.expTable.Nr=[1:app.nrExp]';

            app.expTable.abort=data.abort;

            app.expTable.priorMFlag=data.priorMFlag;
            app.expTable.duringMFlag=data.duringMFlag;

            %loop through experiments
            for i=1:app.nrExp
                %recording exists on z?
                app.expTable.RecZ(i)=data.recExists(i);

                %find recording entries per experiment
                selectquery=['SELECT recSite,recProbe,probeId,penNr FROM tblrecording ' ...
                    'WHERE experimentKey="' num2str(data.experimentKey(i)) '"'];
                dataR=select(app.connSQL,selectquery);

                for c=1:height(dataR)
                    if dataR.probeId(c)==1
                        if ~strcmp(dataR.recSite(c),'')
                            app.expTable.Area1(i)=dataR.recSite(c);
                        end
                        if ~strcmp(dataR.recProbe(c),'')
                            app.expTable.Probe1(i)=dataR.recProbe(c);
                        end
                        if dataR.penNr(c)>-1
                            app.expTable.penNr1(i)=dataR.penNr(c);
                        end
                        
                    else
                        if ~strcmp(dataR.recSite(c),'')
                            app.expTable.Area2(i)=dataR.recSite(c);
                        end
                        if ~strcmp(dataR.recProbe(c),'')
                            app.expTable.Probe2(i)=dataR.recProbe(c);
                        end
                        if dataR.penNr(c)>-1
                            app.expTable.penNr2(i)=dataR.penNr(c);
                        end
                    end
                end

                %find manipulation entries per experiment
                if data.priorMFlag(i)==1 || data.duringMFlag(i)==1
                    selectquery=['SELECT manipDescr,manipDetail,priorDescr FROM tblmanipulation ' ...
                        'WHERE experimentKey="' num2str(data.experimentKey(i)) '"'];
                    dataM=select(app.connSQL,selectquery);
                    app.expTable.Manip(i)=dataM.manipDescr;
                    app.expTable.Details(i)=dataM.manipDetail;
                    app.expTable.priorManip(i)=dataM.priorDescr;
                end


            end

            %display
            app.TableExperiments.Data=app.expTable;
            removeStyle(app.TableExperiments);

            %set table properties
            app.TableExperiments.ColumnName=app.expTable.Properties.VariableNames;
            app.TableExperiments.ColumnWidth={20,50,50,'auto'};
            app.TableExperiments.ColumnEditable=true(1,width(app.expTable));
            app.TableExperiments.ColumnEditable(1:3)=false;

            %initialize analyzer entry
            app.AnalyzerEntry=0;

            %allow edits
            app.EditGroupSql.Enable=1;
            app.UpdatefromID.Enable=1;
            app.AddAnalyzer.Enable=1;
            close(app.connSQL);
        end

        % Cell edit callback: TableExperiments
        function TableExperimentsCellEdit(app, event)
            %define style for cells that are changing content
            diffCell=uistyle('BackgroundColor','yellow');
            addStyle(app.TableExperiments,diffCell,'cell',event.Indices);
            app.expTableEdit(event.Indices(1),event.Indices(2))={1};
        end

        % Button pushed function: EditGroupSql
        function EditGroupSqlPushed(app, event)
            %open GUI to update multiple files at once
            propTable=app.makeGroupTable(2);
            settingsHandle=updateExpInfo(app,app.nrExp,propTable,2);
            waitfor(settingsHandle);
            figure(app.UIDBEntry)
        end

        % Button pushed function: UpdatefromID
        function UpdateFromIDPushed(app, event)
            %find id files and update info
            %update table
            app.expTable=app.TableExperiments.Data;
            animal=app.SelectExpID2.Value;

            %define style for cells that are changing content
            diffCell=uistyle('BackgroundColor','yellow');

            for i=1:height(app.expTable)
                %look for id file
                ananame=[animal '_u' app.expTable.unitNr{i} '_' app.expTable.expNr{i}];
                idName=fullfile(app.SpikePath.Value,animal,ananame,[ananame '_id.mat']);

                if exist(idName,'file')~=0
                    id=load(idName);

                    %check whether it is different starting with the first probe
                    %only makes sense if there is an entry
                    if isfield(id.id,'probes')
                        if ismissing(app.expTable.Area1(i)) || isempty(app.expTable.Area1{i}) ...
                                || strcmp(app.expTable.Area1{i},id.id.probes(1).area)==0
                            app.expTable.Area1(i)=id.id.probes(1).area;
                            app.expTableEdit.Area1(i)=1;
                            addStyle(app.TableExperiments,diffCell,'cell',[i,app.expTableC.Area1]);
                        end

                        if ismissing(app.expTable.Probe1(i)) || isempty(app.expTable.Probe1{i}) ...
                                || strcmp(app.expTable.Probe1(i),id.id.probes(1).type)==0
                            app.expTable.Probe1(i)=id.id.probes(1).type;
                            app.expTableEdit.Probe1(i)=1;
                            addStyle(app.TableExperiments,diffCell,'cell',[i,app.expTableC.Probe1]);
                        end

                        if length(id.id.probes)>1
                            if ismissing(app.expTable.Area2(i)) || isempty(app.expTable.Area2{i}) ...
                                    || strcmp(app.expTable.Area2{i},id.id.probes(2).area)==0
                                app.expTable.Area2(i)=id.id.probes(2).area;
                                app.expTableEdit.Area2(i)=1;
                                addStyle(app.TableExperiments,diffCell,'cell',[i,app.expTableC.Area2]);
                            end

                            if ismissing(app.expTable.Probe2(i)) || isempty(app.expTable.Probe2{i}) ...
                                    || strcmp(app.expTable.Probe2(i),id.id.probes(2).type)==0
                                app.expTable.Probe2(i)=id.id.probes(2).type;
                                app.expTableEdit.Probe2(i)=1;
                                addStyle(app.TableExperiments,diffCell,'cell',[i,app.expTableC.Probe2]);
                            end

                        end
                    end

                end
            end

            %display
            app.TableExperiments.Data=app.expTable;
        end

        % Button pushed function: AddAnalyzer
        function AddAnalyzerButtonPushed(app, event)
            %get analyzer files for a selected experiment - only if not already entered to avoid duplication
            app.expTable=app.TableExperiments.Data;
            app.nrExp=height(app.expTable); %making sure this is up to date

            %generate list that combines units and experiments
            unit_exp=append(app.expTable.unitNr,'_',app.expTable.expNr);
    
            %define style for cells that are changing content
            diffCell=uistyle('BackgroundColor','yellow');

            animal=app.SelectExpID2.Value;

            fpath=fullfile(app.AnalyzerPath.Value,animal ,[animal '*.analyzer']);
            anafiles=dir(fpath);

            %disp(anafiles)
            for i=1:length(anafiles)
                %get unit etc to figure out whether file already entered
                [~,ananame,~]=fileparts(anafiles(i).name);
                anaparts=strsplit(ananame,'_');
                anaunit=anaparts{2}(2:4);
                anaexp=anaparts{3}(1:3);
        
                u_e=[anaunit '_' anaexp];    

                if ~ismember(u_e,unit_exp)
                    app.nrExp=app.nrExp+1;

                    app.createExpEntry();
                    app.expTable.Nr(app.nrExp)=app.nrExp;
                    app.expTable.unitNr(app.nrExp)={anaunit};
                    app.expTable.expNr(app.nrExp)={anaexp};

                    %load analyzer file to get abort info
                    load(fullfile(anafiles(i).folder,anafiles(i).name),'-mat');
                    if exist('abortFlag','var')
                        app.expTable(app.nrExp,app.expTableC.abort)={abortFlag};
                    end

                    app.expTable(app.nrExp,app.expTableC.priorMFlag)={0};
                    app.expTable(app.nrExp,app.expTableC.duringMFlag)={0};

                    %phys data exists?
                    physName=fullfile(app.IntanPath.Value,animal,ananame,[ananame '_info.rhd']);
                    if exist(physName,'file')~=0
                        app.expTable.RecZ(app.nrExp)=1;
                    else
                        app.expTable.RecZ(app.nrExp)=0;
                    end

                    addStyle(app.TableExperiments,diffCell,'row',app.nrExp);
                    app.AnalyzerEntry=1; %if analyzer files added, need to update params table in database
                end


            end

            app.TableExperiments.Data=app.expTable;
        end

        % Button pushed function: UpdateExperimentDB
        function UpdateMySqlDBPushed(app, event)
            %set path as necessary
            addpath([app.GitPath.Value '/Spike/microprobe_wiring/'])
            addpath([app.GitPath.Value '/Spike/microprobe_wiring/headstage and pcb wiring/'])
            addpath([app.GitPath.Value '/analyzer/AnalyzerUtils/'])

            %open connection to database
            app.openSQL();

            %update table
            app.expTable=app.TableExperiments.Data;

            %waitbar
            wb=uiprogressdlg(app.UIDBEntry,'Title','Please wait','Message','Updating database');

            for i=1:height(app.expTableEdit)
                wb.Value=i/height(app.expTableEdit);

                %check whether new analyzer entry required
                if app.AnalyzerEntry==1 && app.expTableEdit{i,1}==1
                    expId=app.SelectExpID2.Value;

                    tableExp=table;
                    tableExp.experimentId={expId};
                    tableExp.unitNr={char(app.expTable.unitNr{i})};
                    tableExp.experimentNr={char(app.expTable.expNr{i})};
                    tableExp.recExists={app.expTable.RecZ(i)};

                    %now load analyzer file
                    fname=fullfile(app.AnalyzerPath.Value,expId,[expId '_u' app.expTable.unitNr{i} '_' app.expTable.expNr{i} '.analyzer']);
                    load(fname,'-mat');
                 
                    %module, rep, randomization, date
                    tableExp.module={Analyzer.modID};
                    tableExp.reps={Analyzer.L.reps};
                    tableExp.trialrand={int32(Analyzer.L.rand)};

                    %looper variables - first full format
                    nLoop=length(Analyzer.L.param);
                    for j=1:nLoop
                        tableExp=addvars(tableExp,Analyzer.L.param{j}(1),Analyzer.L.param{j}(2),{int32(Analyzer.L.param{j}{3})},...
                            'NewVariableNames',{['looperName' num2str(j)],['looperVal' num2str(j)],['looperBlock' num2str(j)]});
                    end
                    %combination of variable and parameter
                    [domains,domval,~]=getdomainvalue(Analyzer);
                    for j=1:length(domains)
                        nrCond=length(unique(domval(:,j)));
                        tableExp=addvars(tableExp,{[domains{j} num2str(nrCond)]},'NewVariableNames',['looperNameCond' num2str(j)]);
                    end
                    sqlwrite(app.connSQL,'tblexperiment',tableExp);

                    selectquery=['SELECT experimentKey FROM tblexperiment ' ...
                        'WHERE experimentId="' expId ...
                        '" AND unitNr="' app.expTable.unitNr{i} ...
                        '" AND experimentNr="' app.expTable.expNr{i} '"'];
                    data=select(app.connSQL,selectquery);
                    expkey=double(data.experimentKey);

                    %also add parameters (only add the ones that are
                    %marked for db)
                    ptable={};
                    ptable{1,1}='experimentKey';
                    ptable{1,2}='pname';
                    ptable{1,3}='pval';
                    pcount=2;
                    for p=1:length(Analyzer.P.param)
                        if Analyzer.P.param{p}{4}==1
                            ptable{pcount,1}=expkey;
                            ptable{pcount,2}=Analyzer.P.param{p}{1};
                            ptable{pcount,3}=num2str(Analyzer.P.param{p}{3});
                            pcount=pcount+1;
                        end
                    end
                    if pcount>2
                        tableParam=cell2table(ptable(2:end,:));
                        tableParam.Properties.VariableNames=ptable(1,:);

                        sqlwrite(app.connSQL,'tblparams',tableParam);
                    end

                end

                %test whether anything was changed (this will add the sites
                %and abort and other info to the newly generated entries as well)
                if any(app.expTableEdit{i,:})

                    %need to find the experiment key for this experiment
                    expId=app.SelectExpID2.Value;

                    selectquery=['SELECT experimentKey FROM tblexperiment ' ...
                        'WHERE experimentId="' expId ...
                        '" AND unitNr="' app.expTable.unitNr{i} ...
                        '" AND experimentNr="' app.expTable.expNr{i} '"'];

                    data=select(app.connSQL,selectquery);
                    expkey=num2str(data.experimentKey);

                    %check whether status on z has changed (this needs to
                    %go into tblExperiment)
                    if app.expTableEdit.RecZ(i)==1
                        update(app.connSQL,'tblexperiment','recExists',app.expTable.RecZ(i),['WHERE experimentkey="' expkey '"']);
                    end

                    if app.expTableEdit.abort(i)==1
                        update(app.connSQL,'tblexperiment','abort',double(app.expTable.abort(i)),['WHERE experimentkey="' expkey '"']);
                    end

                    %find out if any recording info for the first probe changed
                    %if any(app.expTableEdit{i,{'Area1','Probe1','NrSU1','NrMU1','Sorted1','penNr1'}})
                    if any(app.expTableEdit{i,{'Area1','Probe1','penNr1'}})
                        tableRec=table;
                        tableRec.experimentKey={double(data.experimentKey)};
                        if ~ismissing(app.expTable.Area1(i))
                            tableRec.recSite={char(app.expTable.Area1{i})};
                        end
                        if ~ismissing(app.expTable.Probe1(i))
                            tableRec.recProbe={char(app.expTable.Probe1(i))};
                        end
                        tableRec.penNr=app.expTable.penNr1(i);
                        tableRec.probeId=1;

                        %check whether there is already an entry in
                        %tblrecordings
                        selectquery=['SELECT recordingKey FROM tblrecording ' ...
                            'WHERE experimentKey="' expkey ...
                            '" AND probeID="1"'];

                        dataR=select(app.connSQL,selectquery);

                        %if there is an existing record, update the fields
                        if ~isempty(dataR)
                            update(app.connSQL,'tblrecording',tableRec.Properties.VariableNames,tableRec,['WHERE recordingKey="' num2str(dataR.recordingKey) '"']);
                        else %otherwise generate a new entry
                            sqlwrite(app.connSQL,'tblrecording',tableRec);
                        end
                    end %update probe 1

                    %if any(app.expTableEdit{i,{'Area2','Probe2','NrSU2','NrMU2','Sorted2','penNr2'}})
                    if any(app.expTableEdit{i,{'Area2','Probe2','penNr2'}})
                        tableRec=table;
                        tableRec.experimentKey={double(data.experimentKey)};
                        tableRec.recSite={char(app.expTable.Area2{i})};
                        tableRec.recProbe={char(app.expTable.Probe2(i))};
                        tableRec.penNr=app.expTable.penNr2(i);
                        tableRec.probeId=2;

                        %check whether there is already an entry in
                        %tblrecordings
                        selectquery=['SELECT recordingKey FROM tblrecording ' ...
                            'WHERE experimentKey="' expkey ...
                            '" AND probeID="2"'];

                        dataR=select(app.connSQL,selectquery);
                        if ~isempty(dataR)
                            update(app.connSQL,'tblrecording',tableRec.Properties.VariableNames,tableRec,['WHERE recordingKey="' num2str(dataR.recordingKey) '"']);
                        else %otherwise generate a new entry
                            sqlwrite(app.connSQL,'tblrecording',tableRec);
                        end
                        %in this case also make sure Nsites is set to 2
                        update(app.connSQL,'tblexperiment','Nsites',2,['WHERE experimentkey="' expkey '"']);

                    end %update for probe 2


                    if any(app.expTableEdit{i,{'priorMFlag','duringMFlag','Manip','Details','priorManip'}})
                        if app.expTable.priorMFlag(i)==1 || app.expTable.duringMFlag(i)==1
                            tableMan=table;
                            tableMan.manipDescr={char(app.expTable.Manip(i))};
                           
                            if ~ismissing(app.expTable.Details(i))
                                tableMan.manipDetail={char(app.expTable.Details(i))};
                            else 
                                %only do this if this value was actually
                                %updated
                                if app.expTableEdit.Details(i)==1
                                    tableMan.manipDetail={''};
                                end
                            end
                            tableMan.priorDescr={char(app.expTable.priorManip(i))};
                            tableMan.experimentKey={double(data.experimentKey)};

                            %check whether manipulation entry exists already
                            selectquery=['SELECT manipulationKey FROM tblmanipulation ' ...
                                'WHERE experimentKey="' expkey '"'];

                            dataR=select(app.connSQL,selectquery);

                            if ~isempty(dataR)
                                update(app.connSQL,'tblmanipulation',tableMan.Properties.VariableNames,tableMan,['WHERE manipulationKey="' num2str(dataR.manipulationKey) '"']);
                            else
                                sqlwrite(app.connSQL,'tblmanipulation',tableMan);
                            end

                            %also update tblexperiment
                            tableExp=table;
                            tableExp.priorMFlag={double(app.expTable.priorMFlag(i))};
                            tableExp.duringMFlag={double(app.expTable.duringMFlag(i))};

                            update(app.connSQL,'tblexperiment',tableExp.Properties.VariableNames,tableExp,['WHERE experimentkey="' expkey '"']);
                        end
                    end %update manipulation


                    %generate ID file if selected and not already generated
                    if app.GenerateIDFiles2.Value
                        ananame=[expId '_u' app.expTable.unitNr{i} '_' app.expTable.expNr{i}];

                        idName=fullfile(app.SpikePath.Value,expId,ananame,[ananame '_id.mat']);

                        if exist(idName,'file')~=0 %file exists; update
                            %load file first
                            load(idName); %generates id
                            
                            %fix for probes needed to deal with old versus
                            %new format -  only possible if id.probes exist
                            if isfield(id,'probes') && isfield(id.probes,'tipelectrode') %this only exists in the old format
                                %remove fields we don't have any more
                                id.probes=rmfield(id.probes,'tipelectrode');
                                id.probes=rmfield(id.probes,'wiring');
                                id.probes=rmfield(id.probes,'config');
                                id.probes=rmfield(id.probes,'nShanks');
                            end


                        else %file does not exists, generate
                            id=struct;
                            if exist(fullfile(app.SpikePath.Value,expId,ananame),'dir')==0
                                mkdir(fullfile(app.SpikePath.Value,expId,ananame));
                            end
                        end

                        if  ~ismissing(app.expTable.Area1(i)) && ~isempty(app.expTable.Area1{i})
                            if ~ismissing(app.expTable.Probe1(i))
                                ptype=char(app.expTable.Probe1(i));
                                probeTmp=app.makeProbe(ptype);
                                %probeTmp.type=ptype; (part of makeProbe)
                                probeTmp.nChannels=length(probeTmp.channels);
                            end
                            probeTmp.area=char(app.expTable.Area1(i));
                            %may need to delete what was there in case it only partially matches
                            if length(fieldnames(id.probes(1))) ~= length(fieldnames(probeTmp))
                                id.old.probes=id.probes;
                                id=rmfield(id,'probes');
                                id.probes(1)=probeTmp;
                            else
                                id.probes(1)=probeTmp;
                            end
                              
                        end
                        if  ~ismissing(app.expTable.Area2(i)) && ~isempty(app.expTable.Area2{i})
                            if ~ismissing(app.expTable.Probe1(i))
                                ptype=char(app.expTable.Probe2(i));
                                probeTmp=app.makeProbe(ptype);
                                %probeTmp.type=ptype;
                                probeTmp.nChannels=length(probeTmp.channels);
                            end
                            probeTmp.area=char(app.expTable.Area2(i));
                            id.probes(2)=probeTmp;
                        end
                        if ~isfield(id,'isBR')
                            id.isBR=0;
                        end
                        if ~isfield(id,'exptId')
                            id.exptId=ananame;
                        end

                        save(idName,'id')

                        clear id;
                    end
                end %if any
            end %for i
            close(wb);
            close(app.connSQL);
            uiconfirm(app.UIDBEntry,'MySQL database updated.','Confirm done','icon','success');
        end

        % Button pushed function: ReadSort
        function ReadSortButtonPushed(app, event)
            [f,p]=uigetfile('*spkSort*.mat');
            figure(app.UIDBEntry)

            if f~=0

                %information to extract: animal, unit, experiment, version
                fparts=strsplit(f,'_');
                fp2=strsplit(fparts{end},'.');                 
                if ~contains(fp2{1},'spkSort')
                    tSuffix=fp2{1};
                else
                    tSuffix=missing;
                end

                %generate table with info
                app.sortTable=table;
                app.sortTable.experimentId=fparts{1};
                app.sortTable.unitNr=fparts{2}(2:end);
                app.sortTable.experimentNr=fparts{3};
                app.sortTable.probeId=str2num(fparts{4}(2));
                app.sortTable.version=tSuffix;

                %read spkSort file
                load(fullfile(p,f)); %generates spkSort

                %get nr units
                NSU=sum(strcmp(spkSort.unitinfo,'SU'));
                NMU=sum(strcmp(spkSort.unitinfo,'MU'));
            
                app.sortTable.NSU=NSU;
                app.sortTable.NMU=NMU;

                %update table
                app.TableSort.Data=app.sortTable;
                app.TableSort.ColumnName=app.sortTable.Properties.VariableNames;

                app.EnterSortDB.Enable=1;
            end
        end

        % Button pushed function: EnterSortDB
        function EnterSortDBButtonPushed(app, event)
            %open connection to database
            app.openSQL();

            tableOut=table;

            %find experiment number
            selectquery=['SELECT experimentKey FROM tblexperiment ' ...
                    'WHERE experimentId="' app.sortTable.experimentId ...
                    '" AND unitNr="' app.sortTable.unitNr ...
                    '" AND experimentNr="' app.sortTable.experimentNr '"'];

            data=select(app.connSQL,selectquery);
            if isempty(data)
                uialert(app.UIDBEntry,'Experiment does not exist in DB!','Error');
                return;
            end
            expkey=num2str(data.experimentKey);

            %find recording number
            selectquery=['SELECT recordingKey FROM tblrecording ' ...
                'WHERE experimentKey="' expkey ...
                '" AND probeId="' num2str(app.sortTable.probeId) '"'];

            data=select(app.connSQL,selectquery);
            if isempty(data)
                uialert(app.UIDBEntry,'Recording does not exist in DB!','Error');
                return;
            end
            tableOut.recordingKey=double(data.recordingKey);
            
            %need to update spikeSorted flag
            update(app.connSQL,'tblrecording','spikeSorted',1,['WHERE recordingKey="' num2str(tableOut.recordingKey) '"']);

            %now test whether there already is an entry in the sort table
            if ismissing(app.sortTable.version)
                selectquery=['SELECT sortingKey FROM tblsort ' ...
                    'WHERE recordingKey="' num2str(tableOut.recordingKey) ...
                    '" AND sortVersion IS NULL'];
                
            else
                selectquery=['SELECT sortingKey FROM tblsort ' ...
                    'WHERE recordingKey="' num2str(tableOut.recordingKey) ...
                    '" AND sortVersion="' app.sortTable.version '"'];
                tableOut.sortVersion={app.sortTable.version};
            end

            tableOut.NSingleUnit=app.sortTable.NSU;
            tableOut.NMultiUnit=app.sortTable.NMU;
            tableOut.oldSortPipeline=0;

            data=select(app.connSQL,selectquery);
            if isempty(data)
                %generate new entry in database
                sqlwrite(app.connSQL,'tblsort',tableOut);
                uiconfirm(app.UIDBEntry,'MySQL database updated.','Confirm done','icon','success');
            else
                %check first whether the entry should be replaced
                sel=uiconfirm(app.UIDBEntry,'Overwrite previous numbers for this version?',...
                    'Confirm overvrite','Icon','warning');
                switch sel
                    case 'OK'
                        update(app.connSQL,'tblsort','NSingleUnit',tableOut.NSingleUnit,['WHERE sortingKey="' num2str(data.sortingKey) '"']);
                        update(app.connSQL,'tblsort','NMultiUnit',tableOut.NMultiUnit,['WHERE sortingKey="' num2str(data.sortingKey) '"']);
                        uiconfirm(app.UIDBEntry,'MySQL database updated.','Confirm done','icon','success');
                    case 'Cancel'
                        return;
                end
            end          

            %close database
            close(app.connSQL);
        end

        % Button pushed function: ReadDataset
        function ReadDatasetButtonPushed(app, event)
            %get excel file name
            [f,p]=uigetfile('*.xlsx');
            figure(app.UIDBEntry)

            if f~=0
                app.datasetFile=fullfile(p,f);

                %read table
                xlsTable=readtable(app.datasetFile,'Sheet','Dataset');

                %test whether multiple projects were entered in the same
                %sheet - don't proceed in that case (we're assuming one
                %file per project below)
                if length(unique(xlsTable.Project))>1
                    uialert(app.UIDBEntry,'Only one project per project spreadsheet!','Error');
                    return;
                end

                %transform into output table
                %initial call - in theory this should work, but ended up
                %causing issues? alternative works more reliably?
                %app.projTable=convertvars(xlsTable,{'Probe','Include'},'cell');
                app.projTable=convertvars(xlsTable,{'Probe','Include'},@num2cell);

                app.projTable.Properties.VariableNames{'Animal'}='experimentId';
                app.projTable.Properties.VariableNames{'Unit'}='unitNr';
                app.projTable.Properties.VariableNames{'Experiment'}='experimentNr';
                app.projTable.Properties.VariableNames{'Probe'}='probeId';
                app.projTable.Properties.VariableNames{'Suffix'}='sortSuffix';
                app.projTable.Properties.VariableNames{'Include'}='includeFlag';
                app.projTable.Properties.VariableNames{'Notes'}='includeNotes';
                app.projTable.Properties.VariableNames{'Initials'}='projOwner';
                app.projTable.Properties.VariableNames{'Project'}='projName';

                %remove entries where suffix is none
                idx=find(strcmp(app.projTable.sortSuffix,'none'));
                if ~isempty(idx)
                    app.projTable.sortSuffix{idx}='';
                end

                %show in table
                app.TableDataset.Data=app.projTable;
                app.TableDataset.ColumnName=app.projTable.Properties.VariableNames;

                %activate entry button
                app.EnterDatasetDB.Enable=1;

               
            end
        end

        % Button pushed function: EnterDatasetDB
        function EnterDatasetDBButtonPushed(app, event)
            %open connection to database
            app.openSQL();

            %find keys for the experiment and recording and add to table (for
            %cross-referencing)
            for i=1:height(app.projTable)
                %construct search string 1
                selectquery=['SELECT experimentKey FROM tblexperiment ' ...
                    'WHERE experimentId="' app.projTable.experimentId{i} ...
                    '" AND unitNr="' app.projTable.unitNr{i} ...
                    '" AND experimentNr="' app.projTable.experimentNr{i} '"'];

                data=select(app.connSQL,selectquery);
                if isempty(data)
                    uialert(app.UIDBEntry,'Experiment does not exist in DB!','Error');
                    return;
                end
                expkey=num2str(data.experimentKey);
                app.projTable.experimentKey{i}=double(data.experimentKey);

                %construct search string 2
                selectquery=['SELECT recordingKey FROM tblrecording ' ...
                    'WHERE experimentKey="' expkey ...
                    '" AND probeId="' num2str(app.projTable.probeId{i}) '"'];

                data=select(app.connSQL,selectquery);
                if isempty(data)
                    expStr=[app.projTable.experimentId{i} '_' app.projTable.unitNr{i} ...
                        '_' app.projTable.experimentNr{i} '_p' num2str(app.projTable.probeId{i})];
                    uialert(app.UIDBEntry,...
                        {'Recording does not exist in DB!',...
                        expStr},...
                        'Error');
                    return;
                end
                app.projTable.recordingKey{i}=double(data.recordingKey);

            end

            %test whether there are old entries for the project that would
            %need to be deleted
            selectquery=['SELECT projectKey FROM tblproject ' ...
                    'WHERE projName="' app.projTable.projName{1} '"'];
            
            data=select(app.connSQL,selectquery);
            if ~isempty(data)
                sel=uiconfirm(app.UIDBEntry,'Delete previous entries for this project?',...
                    'Confirm delete','Icon','warning');
                switch sel
                    case 'OK'
                        %need to do this row by row because of safe mode
                        for i=1:height(data)
                            delquery=['DELETE FROM tblproject ' ...
                                'WHERE projectKey="' num2str(data.projectKey(i)) '"'];
                            exec(app.connSQL,delquery);
                        end
                    case 'Cancel'
                        return;
                end
            end

            %write data
            sqlwrite(app.connSQL,'tblproject',app.projTable);
            uiconfirm(app.UIDBEntry,'MySQL database updated.','Confirm done','icon','success');

            %copy and rename dataset excel file
            %get extension from inital file
            [~,~,ext]=fileparts(app.datasetFile); %contains the dot
            
            %get date
            dstring=char(datetime('today','format','MM.dd.yyyy'));

            newFile=fullfile(app.ProjectPath.Value,['project_' app.projTable.projOwner{1} '_' app.projTable.projName{1} ...
                '_' dstring ext]);


            copyfile(app.datasetFile,newFile);


            %close database
            close(app.connSQL);
           

        end

        % Button pushed function: ReadPaperData
        function ReadPaperDataButtonPushed(app, event)
            %read spreadsheet for paper dataset
            
            [f,p]=uigetfile('*.xlsx');
            figure(app.UIDBEntry)

            if f~=0
                app.datasetFile=fullfile(p,f);

                %read table
                xlsTable=readtable(app.datasetFile,'Sheet','Dataset');
                
                %remove 'none' entries
                searchResult = varfun(@(x) contains(string(x), 'none'), xlsTable);
                [idxR,idxC]=find(searchResult{:,:}==1);
                for i=1:length(idxR)
                    xlsTable{idxR(i),idxC(i)}={''};
                end

                %show in gui
                app.paperTable=xlsTable;
                app.TablePaper.Data=app.paperTable;
                app.TablePaper.ColumnName=app.paperTable.Properties.VariableNames;

               
                %activate entry button
                app.EnterPaperDB.Enable=1;
                app.CopyFiles.Enable=1;
            end

        end

        % Button pushed function: EnterPaperDB
        function EnterPaperDBButtonPushed(app, event)
            %enter data set used in a paper
            %start with paper info
            %open connection to database
            app.openSQL();

            %test whether paper exists - refuse to proceed because
            %associated file maneuvers make this too complicated
            selectquery=['SELECT paperKey FROM tblpaper ' ...
                    'WHERE paperLabel="' app.PaperLabel.Value '"'];
            data=select(app.connSQL,selectquery);
            if ~isempty(data)
                uialert(app.UIDBEntry,'Paper already exist! Cannot proceed.','Error');
                return;
            end

            %paper does not exist, so proceed
            tableOut=table;
            tableOut.paperLabel={app.PaperLabel.Value};
            tableOut.title={app.PaperTitle.Value};
            tableOut.authors={app.PaperAuthors.Value};
            tableOut.journal={app.PaperJournal.Value};
            tableOut.year={int32(app.PaperYear.Value)};

            sqlwrite(app.connSQL,'tblpaper',tableOut);

            %now loop through table
            for i=1:1 %height(app.paperTable)
              
                tableUOut=table;

                %copy relevant data
                tableUOut.experimentId=app.paperTable.Animal(i);
                tableUOut.unitNr=app.paperTable.Unit(i);
                tableUOut.experimentNr=app.paperTable.Experiment(i);
                tableUOut.unitChNr=app.paperTable.UnitNr(i);
                tableUOut.datatype=app.paperTable.Datatype(i);

                %get experiment key
                selectquery=['SELECT experimentKey FROM tblexperiment ' ...
                    'WHERE experimentId="' app.paperTable.Animal{i} ...
                    '" AND unitNr="' app.paperTable.Unit{i} ...
                    '" AND experimentNr="' app.paperTable.Experiment{i} '"'];

                data=select(app.connSQL,selectquery);
                if isempty(data)
                    uialert(app.UIDBEntry,'Experiment does not exist in DB!','Error');
                    return;
                end
                expkey=num2str(data.experimentKey);
                tableUOut.experimentKey={double(data.experimentKey)};

                %get recording key
                selectquery=['SELECT recordingKey FROM tblrecording ' ...
                    'WHERE experimentKey="' expkey ...
                    '" AND probeId="' num2str(app.paperTable.Probe(i)) '"'];

                disp(selectquery)

                data=select(app.connSQL,selectquery);
                if isempty(data)
                    uialert(app.UIDBEntry,{'Recording does not exist in DB!',...
                        [app.paperTable.Animal{i} ' u' app.paperTable.Unit{i} ' e' app.paperTable.Experiment{i}]},...
                        'Error');
                    return;
                end
                reckey=num2str(data.recordingKey);
                tableUOut.recordingKey={double(data.recordingKey)};

                %get sorting key - need to check for SU versus MU
                if isempty(app.paperTable.spkSortSuffix{i})
                    selectquery=['SELECT sortingKey FROM tblsort ' ...
                        'WHERE recordingKey="' reckey '"'];
                else
                    selectquery=['SELECT sortingKey FROM tblsort ' ...
                        'WHERE recordingKey="' reckey ...
                        '" AND sortVersion="' app.paperTable.spkSortSuffix{i} '"'];
                end
                
                data=select(app.connSQL,selectquery);
                tableUOut.sortingKey={double(data.sortingKey)};

                %get paper key
                selectquery=['SELECT paperKey FROM tblpaper ' ...
                    'WHERE paperLabel="' app.PaperLabel.Value '"'];
                data=select(app.connSQL,selectquery);
                tableUOut.paperKey={double(data.paperKey)};

                disp(tableUOut)

                %enter into table

                %add to figure table
                figTmp=app.paperTable.Figure{i};
                figNr=strsplit(figTmp,',');
                for f=1:length(figNr)
                    %enter into table


                end
            
            end
            %save spreadsheet
            %copyfile(app.datasetFile,newFile);

            uiconfirm(app.UIDBEntry,'MySQL database updated.','Confirm done','icon','success');

           
            %close database
            close(app.connSQL);
        end

        % Button pushed function: CopyFiles
        function CopyFilesButtonPushed(app, event)
             %copy from ephys data to paperData

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIDBEntry = uifigure('Visible', 'off');
            app.UIDBEntry.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIDBEntry);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.mySqlTest = uibutton(app.SettingsTab, 'push');
            app.mySqlTest.ButtonPushedFcn = createCallbackFcn(app, @mySqlTestPushed, true);
            app.mySqlTest.Tag = 'bmySqlTest';
            app.mySqlTest.Text = 'Test mySql DB';
            app.AccessTest = uibutton(app.SettingsTab, 'push');
            app.AccessTest.ButtonPushedFcn = createCallbackFcn(app, @AccessTestPushed, true);
            app.AccessTest.Tag = 'bAccessTest';
            app.AccessTest.Text = 'Test Access DB';
            app.AnalyzerPathEditFieldLabel = uilabel(app.SettingsTab);
            app.AnalyzerPathEditFieldLabel.Text = 'Analyzer Path';
            app.RawintandatapathLabel = uilabel(app.SettingsTab);
            app.RawintandatapathLabel.Text = 'Raw intan data path';
            app.AccessdatabasenameEditFieldLabel = uilabel(app.SettingsTab);
            app.AccessdatabasenameEditFieldLabel.Text = 'Access database name';
            app.mySqldatabasenameLabel = uilabel(app.SettingsTab);
            app.mySqldatabasenameLabel.Text = 'mySql database name';
            app.MySqlDBEdit = uieditfield(app.SettingsTab, 'text');
            app.MySqlDBEdit.Value = 'ephysDatabase';
            app.AccessDBEdit = uieditfield(app.SettingsTab, 'text');
            app.AccessDBEdit.Value = 'ferretDatabase';
            app.BrowseProject = uibutton(app.SettingsTab, 'push');
            app.BrowseProject.ButtonPushedFcn = createCallbackFcn(app, @BrowseProjectButtonPushed, true);
            app.BrowseProject.Tag = 'bAnapath';
            app.BrowseProject.Text = 'Find';
            app.ProcesseddatapathLabel_2 = uilabel(app.SettingsTab);
            app.ProcesseddatapathLabel_2.Text = 'Project file path';
            app.ProjectPath = uieditfield(app.SettingsTab, 'text');
            app.ProjectPath.Value = 'Z:\EphysNew\projectFiles';
            app.BrowseSpike = uibutton(app.SettingsTab, 'push');
            app.BrowseSpike.ButtonPushedFcn = createCallbackFcn(app, @BrowseSpikeButtonPushed, true);
            app.BrowseSpike.Tag = 'bAnapath';
            app.BrowseSpike.Text = 'Find';
            app.ProcesseddatapathLabel = uilabel(app.SettingsTab);
            app.ProcesseddatapathLabel.Text = 'Processed data path';
            app.SpikePath = uieditfield(app.SettingsTab, 'text');
            app.SpikePath.Value = 'Z:\EphysNew\processedSpikes';
            app.BrowseIntan = uibutton(app.SettingsTab, 'push');
            app.BrowseIntan.ButtonPushedFcn = createCallbackFcn(app, @BrowseIntanPushed, true);
            app.BrowseIntan.Tag = 'bAnapath';
            app.BrowseIntan.Text = 'Find';
            app.IntanPath = uieditfield(app.SettingsTab, 'text');
            app.IntanPath.Value = 'Z:\EphysNew\data';
            app.BrowseAnalyzer = uibutton(app.SettingsTab, 'push');
            app.BrowseAnalyzer.ButtonPushedFcn = createCallbackFcn(app, @BrowseAnalyzerPushed, true);
            app.BrowseAnalyzer.Tag = 'bAnapath';
            app.BrowseAnalyzer.Text = 'Find';
            app.AnalyzerPath = uieditfield(app.SettingsTab, 'text');
            app.AnalyzerPath.Tag = 'anapath';
            app.AnalyzerPath.Value = 'Z:\EphysNew\analyzer';
            app.BrowseGit = uibutton(app.SettingsTab, 'push');
            app.BrowseGit.ButtonPushedFcn = createCallbackFcn(app, @BrowseGitButtonPushed, true);
            app.BrowseGit.Tag = 'bAnapath';
            app.BrowseGit.Text = 'Find';
            app.GitPathLabel = uilabel(app.SettingsTab);
            app.GitPathLabel.Text = 'Git Path';
            app.GitPath = uieditfield(app.SettingsTab, 'text');
            app.GitPath.Tag = 'anapath';
            app.EnterAnimalTab = uitab(app.TabGroup);
            app.EnterAnimalTab.Title = 'Enter recording session';
            app.Initials = uieditfield(app.EnterAnimalTab, 'text');
            app.Terminal = uicheckbox(app.EnterAnimalTab);
            app.Terminal.Tag = 'terminalExp';
            app.Terminal.Text = 'Terminal?';
            app.Terminal.Value = true;
            app.EartagEditFieldLabel = uilabel(app.EnterAnimalTab);
            app.EartagEditFieldLabel.Text = 'Ear tag';
            app.Eartag = uieditfield(app.EnterAnimalTab, 'text');
            app.Eartag.Tag = 'eartag';
            app.ExperimentIDEditFieldLabel = uilabel(app.EnterAnimalTab);
            app.ExperimentIDEditFieldLabel.Text = 'Experiment ID';
            app.ExperimentID = uieditfield(app.EnterAnimalTab, 'text');
            app.ExperimentID.Tag = 'expid';
            app.ExperimentDurDropDownLabel = uilabel(app.EnterAnimalTab);
            app.ExperimentDurDropDownLabel.Text = 'Experiment Dur.';
            app.ExperimentDur = uidropdown(app.EnterAnimalTab);
            app.ExperimentDur.Items = {'<12 hr', 'same day', 'next day', '2 days'};
            app.ExperimentDur.Tag = 'expDur';
            app.ExperimentDur.Value = 'same day';
            app.ExperimentDateLabel = uilabel(app.EnterAnimalTab);
            app.ExperimentDateLabel.Text = 'Experiment Date';
            app.ExperimentDate = uidatepicker(app.EnterAnimalTab);
            app.ExperimentDate.ValueChangedFcn = createCallbackFcn(app, @ExpDateChanged, true);
            app.RoomLabel = uilabel(app.EnterAnimalTab);
            app.RoomLabel.Text = 'Room';
            app.ExperimentRoom = uidropdown(app.EnterAnimalTab);
            app.ExperimentRoom.Items = {'352A', '352C', '361'};
            app.ExperimentRoom.Tag = 'expDur';
            app.ExperimentRoom.Value = '352A';
            app.EnterAnimalDB = uibutton(app.EnterAnimalTab, 'push');
            app.EnterAnimalDB.ButtonPushedFcn = createCallbackFcn(app, @EnterAnimalDBPushed, true);
            app.EnterAnimalDB.Text = 'Enter into Access and MySql DB';
            app.Warning = uicheckbox(app.EnterAnimalTab);
            app.Warning.Text = 'Warning - General problems w/ experiment';
            app.Label = uilabel(app.EnterAnimalTab);
            app.Label.Text = {'Additional steps:'; '1. Copy OneNote pages into Ephys notebook on Z'; '2. Scan vitals record, save in Z:\EphysNew\vitals'; '3. If not done already: Scan animal sheet, save in'; 'Z:\Database\Animal information, add link to '; 'animal record in Access DB '};
            app.FindAIButton = uibutton(app.EnterAnimalTab, 'push');
            app.FindAIButton.ButtonPushedFcn = createCallbackFcn(app, @FindAIButtonPushed, true);
            app.FindAIButton.Text = 'Find';
            app.PathtoanimalinformationsheetEditFieldLabel = uilabel(app.EnterAnimalTab);
            app.PathtoanimalinformationsheetEditFieldLabel.Text = 'Path to animal information sheet';
            app.LinkAISheet = uieditfield(app.EnterAnimalTab, 'text');
            app.LinkAISheet.Value = 'Z:\Database\Animal information';
            app.Label_2 = uilabel(app.EnterAnimalTab);
            app.Label_2.Text = {'Note: Proceed to ''Enter experiments'' to enter'; 'the experiments for this recording session.'};
            app.UpdateAccessDBPanel = uipanel(app.EnterAnimalTab);
            app.UpdateAccessDBPanel.Title = 'Update animal info in Access DB';
            app.UpdateSex = uicheckbox(app.UpdateAccessDBPanel);
            app.UpdateSex.Text = '';
            app.UpdatesexLabel = uilabel(app.UpdateAccessDBPanel);
            app.UpdatesexLabel.Text = 'Update sex?';
            app.AnimalSex = uidropdown(app.UpdateAccessDBPanel);
            app.AnimalSex.Items = {'Female', 'Male'};
            app.AnimalSex.Value = 'Female';
            app.UpdateRearing = uicheckbox(app.UpdateAccessDBPanel);
            app.UpdateRearing.Text = '';
            app.UpdaterearingDropDownLabel = uilabel(app.UpdateAccessDBPanel);
            app.UpdaterearingDropDownLabel.Text = 'Update rearing?';
            app.AnimalRearing = uidropdown(app.UpdateAccessDBPanel);
            app.AnimalRearing.Items = {'DR', 'BLS', 'LLS', 'RLS'};
            app.AnimalRearing.Value = 'DR';
            app.StartDateDatePickerLabel = uilabel(app.UpdateAccessDBPanel);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.RearingStart = uidatepicker(app.UpdateAccessDBPanel);
            app.StopDateDatePickerLabel = uilabel(app.UpdateAccessDBPanel);
            app.StopDateDatePickerLabel.Text = 'Stop Date';
            app.RearingStop = uidatepicker(app.UpdateAccessDBPanel);
            app.Label_3 = uilabel(app.UpdateAccessDBPanel);
            app.Label_3.Text = {'Leave empty to keep previously'; 'set value; needs to be set for new entry'};
            app.UpdateEO = uicheckbox(app.UpdateAccessDBPanel);
            app.UpdateEO.Text = '';
            app.AnimalEOR = uidatepicker(app.UpdateAccessDBPanel);
            app.SeteyeopeningLLabel = uilabel(app.UpdateAccessDBPanel);
            app.SeteyeopeningLLabel.Text = 'Eye opening L';
            app.AnimalEOL = uidatepicker(app.UpdateAccessDBPanel);
            app.TypeEditFieldLabel = uilabel(app.UpdateAccessDBPanel);
            app.TypeEditFieldLabel.Text = 'Type';
            app.EOType = uieditfield(app.UpdateAccessDBPanel, 'numeric');
            app.EOType.Limits = [0 1];
            app.Label_4 = uilabel(app.UpdateAccessDBPanel);
            app.Label_4.Text = {'Eye opening date: Both eyes 50% open'; 'Type: 0 - animal specific, 1 - litter date'};
            app.EyeopeningRDatePickerLabel = uilabel(app.UpdateAccessDBPanel);
            app.EyeopeningRDatePickerLabel.Text = 'Eye opening R';
            app.InitialsEditFieldLabel = uilabel(app.EnterAnimalTab);
            app.InitialsEditFieldLabel.Text = 'Initials';
            app.EnterExperimentsTab = uitab(app.TabGroup);
            app.EnterExperimentsTab.Title = 'Enter experiments';
            app.ExperimentIDDropDownLabel = uilabel(app.EnterExperimentsTab);
            app.ExperimentIDDropDownLabel.Text = 'Experiment ID';
            app.SelectExpID1 = uidropdown(app.EnterExperimentsTab);
            app.SelectExpID1.Items = {};
            app.SelectExpID1.Value = {};
            app.TableAnalyzer = uitable(app.EnterExperimentsTab);
            app.TableAnalyzer.ColumnName = '';
            app.TableAnalyzer.RowName = {};
            app.EnterExperimentDB = uibutton(app.EnterExperimentsTab, 'push');
            app.EnterExperimentDB.ButtonPushedFcn = createCallbackFcn(app, @EnterExperimentDBPushed, true);
            app.EnterExperimentDB.Enable = 'off';
            app.EnterExperimentDB.Text = 'Process experiments';
            app.EditGroup = uibutton(app.EnterExperimentsTab, 'push');
            app.EditGroup.ButtonPushedFcn = createCallbackFcn(app, @EditGroupPushed, true);
            app.EditGroup.Enable = 'off';
            app.EditGroup.Text = 'Edit group';
            app.GetAnalyzer = uibutton(app.EnterExperimentsTab, 'push');
            app.GetAnalyzer.ButtonPushedFcn = createCallbackFcn(app, @GetAnalyzerPushed, true);
            app.GetAnalyzer.Text = 'Get Analyzer';
            app.GenerateIDFiles = uicheckbox(app.EnterExperimentsTab);
            app.GenerateIDFiles.Text = 'Generate ID files';
            app.GenerateIDFiles.Value = true;
            app.UpdateExperimentsTab = uitab(app.TabGroup);
            app.UpdateExperimentsTab.Title = 'Update experiments';
            app.ExperimentIDDropDownLabel_2 = uilabel(app.UpdateExperimentsTab);
            app.ExperimentIDDropDownLabel_2.Text = 'Experiment ID';
            app.UpdatefromID = uibutton(app.UpdateExperimentsTab, 'push');
            app.UpdatefromID.ButtonPushedFcn = createCallbackFcn(app, @UpdateFromIDPushed, true);
            app.UpdatefromID.Enable = 'off';
            app.UpdatefromID.Text = 'Update from ID files';
            app.UpdateExperimentDB = uibutton(app.UpdateExperimentsTab, 'push');
            app.UpdateExperimentDB.ButtonPushedFcn = createCallbackFcn(app, @UpdateMySqlDBPushed, true);
            app.UpdateExperimentDB.Text = 'Update mySql DB';
            app.EditGroupSql = uibutton(app.UpdateExperimentsTab, 'push');
            app.EditGroupSql.ButtonPushedFcn = createCallbackFcn(app, @EditGroupSqlPushed, true);
            app.EditGroupSql.Enable = 'off';
            app.EditGroupSql.Text = 'Edit group';
            app.GenerateIDFiles2 = uicheckbox(app.UpdateExperimentsTab);
            app.GenerateIDFiles2.Text = 'Update or generate ID files';
            app.GenerateIDFiles2.Value = true;
            app.AddAnalyzer = uibutton(app.UpdateExperimentsTab, 'push');
            app.AddAnalyzer.ButtonPushedFcn = createCallbackFcn(app, @AddAnalyzerButtonPushed, true);
            app.AddAnalyzer.Enable = 'off';
            app.AddAnalyzer.Text = 'Add analyzer files';
            app.TableExperiments = uitable(app.UpdateExperimentsTab);
            app.TableExperiments.ColumnName = '';
            app.TableExperiments.RowName = {};
            app.TableExperiments.CellEditCallback = createCallbackFcn(app, @TableExperimentsCellEdit, true);
            app.GetSqlRecords = uibutton(app.UpdateExperimentsTab, 'push');
            app.GetSqlRecords.ButtonPushedFcn = createCallbackFcn(app, @GetSqlRecordsPushed, true);
            app.GetSqlRecords.Text = 'Get records from mySql DB';
            app.SelectExpID2 = uidropdown(app.UpdateExperimentsTab);
            app.SelectExpID2.Items = {};
            app.SelectExpID2.Editable = 'on';
            app.SelectExpID2.ValueChangedFcn = createCallbackFcn(app, @SelectExpID2ValueChanged, true);
            app.SelectExpID2.Value = {};
            app.UpdateSortTab = uitab(app.TabGroup);
            app.UpdateSortTab.Title = 'Update sort';
            app.EnterSortDB = uibutton(app.UpdateSortTab, 'push');
            app.EnterSortDB.ButtonPushedFcn = createCallbackFcn(app, @EnterSortDBButtonPushed, true);
            app.EnterSortDB.Enable = 'off';
            app.EnterSortDB.Text = 'Enter into mySql DB';
            app.TableSort = uitable(app.UpdateSortTab);
            app.TableSort.ColumnName = '';
            app.TableSort.RowName = {};
            app.ReadSort = uibutton(app.UpdateSortTab, 'push');
            app.ReadSort.ButtonPushedFcn = createCallbackFcn(app, @ReadSortButtonPushed, true);
            app.ReadSort.Text = 'Read spkSort file';
            app.InformationtobeenteredLabel = uilabel(app.UpdateSortTab);
            app.InformationtobeenteredLabel.Text = 'Information to be entered:';
            app.EnterDatasetTab = uitab(app.TabGroup);
            app.EnterDatasetTab.Title = 'Enter project dataset';
            app.EnterDatasetDB = uibutton(app.EnterDatasetTab, 'push');
            app.EnterDatasetDB.ButtonPushedFcn = createCallbackFcn(app, @EnterDatasetDBButtonPushed, true);
            app.EnterDatasetDB.Enable = 'off';
            app.EnterDatasetDB.Text = 'Enter into mySql DB';
            app.TableDataset = uitable(app.EnterDatasetTab);
            app.TableDataset.ColumnName = '';
            app.TableDataset.RowName = {};
            app.ReadDataset = uibutton(app.EnterDatasetTab, 'push');
            app.ReadDataset.ButtonPushedFcn = createCallbackFcn(app, @ReadDatasetButtonPushed, true);
            app.ReadDataset.Text = 'Read dataset spreadsheet';
            app.EnterPaperTab = uitab(app.TabGroup);
            app.EnterPaperTab.Title = 'Enter paper info';
            app.LabelEditFieldLabel = uilabel(app.EnterPaperTab);
            app.LabelEditFieldLabel.Text = 'Paper label (short)';
            app.CopyFiles = uibutton(app.EnterPaperTab, 'push');
            app.CopyFiles.ButtonPushedFcn = createCallbackFcn(app, @CopyFilesButtonPushed, true);
            app.CopyFiles.Enable = 'off';
            app.CopyFiles.Text = 'Copy files';
            app.EnterPaperDB = uibutton(app.EnterPaperTab, 'push');
            app.EnterPaperDB.ButtonPushedFcn = createCallbackFcn(app, @EnterPaperDBButtonPushed, true);
            app.EnterPaperDB.Enable = 'off';
            app.EnterPaperDB.Text = 'Enter into mySql DB';
            app.TablePaper = uitable(app.EnterPaperTab);
            app.TablePaper.ColumnName = '';
            app.TablePaper.RowName = {};
            app.ReadPaperData = uibutton(app.EnterPaperTab, 'push');
            app.ReadPaperData.ButtonPushedFcn = createCallbackFcn(app, @ReadPaperDataButtonPushed, true);
            app.ReadPaperData.Text = 'Read dataset spreadsheet';
            app.TitleEditFieldLabel = uilabel(app.EnterPaperTab);
            app.TitleEditFieldLabel.Text = 'Title';
            app.AuthorsLastnamesEditFieldLabel = uilabel(app.EnterPaperTab);
            app.AuthorsLastnamesEditFieldLabel.Text = 'Authors (Last names)';
            app.JournalEditFieldLabel = uilabel(app.EnterPaperTab);
            app.JournalEditFieldLabel.Text = 'Journal';
            app.YearEditFieldLabel = uilabel(app.EnterPaperTab);
            app.YearEditFieldLabel.Text = 'Year';
            app.PaperYear = uieditfield(app.EnterPaperTab, 'numeric');
            app.PaperYear.Value = 2026;
            app.PaperJournal = uieditfield(app.EnterPaperTab, 'text');
            app.PaperAuthors = uieditfield(app.EnterPaperTab, 'text');
            app.PaperTitle = uieditfield(app.EnterPaperTab, 'text');
            app.PaperLabel = uieditfield(app.EnterPaperTab, 'text');
            % Show the figure after all components are created
            app.UIDBEntry.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = physiologyDB(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIDBEntry)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIDBEntry)
        end
    end
end