classdef updateExpInfo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIUpdateExp                matlab.ui.Figure
        RecZCheckBox               matlab.ui.control.CheckBox
        ToSpinner                  matlab.ui.control.Spinner
        ToSpinnerLabel             matlab.ui.control.Label
        FromSpinner                matlab.ui.control.Spinner
        FromExpNrSpinnerLabel      matlab.ui.control.Label
        CancelButton               matlab.ui.control.Button
        DoneButton                 matlab.ui.control.Button
        DetailsEditField           matlab.ui.control.EditField
        DetailsEditFieldLabel      matlab.ui.control.Label
        Probe2EditField            matlab.ui.control.EditField
        Probe2EditFieldLabel       matlab.ui.control.Label
        Area2EditField             matlab.ui.control.EditField
        Area2EditFieldLabel        matlab.ui.control.Label
        ManipulationDropDown       matlab.ui.control.DropDown
        ManipulationDropDownLabel  matlab.ui.control.Label
        Probe1EditField            matlab.ui.control.EditField
        Probe1EditFieldLabel       matlab.ui.control.Label
        Area1EditField             matlab.ui.control.EditField
        Area1EditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainGui % Description
        nrExp
        returnType %analyzer or sql update
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, nrExp, type)
            app.mainGui=mainapp;
            app.nrExp=nrExp;
            app.returnType=type;
            
            app.FromSpinner.Limits=[1,app.nrExp];
            app.ToSpinner.Limits=[1,app.nrExp];
            app.ToSpinner.Value=app.nrExp;

            if type==1 %initial entry into database, rec file is automatically checked
                app.RecZCheckBox.Visible=0;
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            %return values to main app
            ExpFrom=app.FromSpinner.Value;
            ExpTo=app.ToSpinner.Value;
            Area1=app.Area1EditField.Value;
            Probe1=app.Probe1EditField.Value;
            Area2=app.Area2EditField.Value;
            Probe2=app.Probe2EditField.Value;
            Manip=app.ManipulationDropDown.Value;
            ManipDetails=app.DetailsEditField.Value;
            RecZ=app.RecZCheckBox.Value;

            getExpInfo(app.mainGui,ExpFrom,ExpTo,Area1,Probe1,Area2,Probe2,Manip,ManipDetails,RecZ,app.returnType);
            
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            getExpInfo(app.mainGui,-1,0,'','','','','None','','');
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIUpdateExp = uifigure('Visible', 'off');
            app.UIUpdateExp.Name = 'MATLAB App';
            app.Area1EditFieldLabel = uilabel(app.UIUpdateExp);
            app.Area1EditFieldLabel.Text = 'Area 1';
            app.Area1EditField = uieditfield(app.UIUpdateExp, 'text');
            app.Probe1EditFieldLabel = uilabel(app.UIUpdateExp);
            app.Probe1EditFieldLabel.Text = 'Probe 1';
            app.Probe1EditField = uieditfield(app.UIUpdateExp, 'text');
            app.ManipulationDropDownLabel = uilabel(app.UIUpdateExp);
            app.ManipulationDropDownLabel.Text = 'Manipulation';
            app.ManipulationDropDown = uidropdown(app.UIUpdateExp);
            app.ManipulationDropDown.Items = {'', 'None', 'Training', 'Muscimol', 'Cooling', 'Postcool', 'Opto inact', 'Opto act'};
            app.ManipulationDropDown.Value = '';
            app.Area2EditFieldLabel = uilabel(app.UIUpdateExp);
            app.Area2EditFieldLabel.Text = 'Area 2';
            app.Area2EditField = uieditfield(app.UIUpdateExp, 'text');
            app.Probe2EditFieldLabel = uilabel(app.UIUpdateExp);
            app.Probe2EditFieldLabel.Text = 'Probe 2';
            app.Probe2EditField = uieditfield(app.UIUpdateExp, 'text');
            app.DetailsEditFieldLabel = uilabel(app.UIUpdateExp);
            app.DetailsEditFieldLabel.Text = 'Details';
            app.DetailsEditField = uieditfield(app.UIUpdateExp, 'text');
            app.DoneButton = uibutton(app.UIUpdateExp, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.UIUpdateExp, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.FromExpNrSpinnerLabel = uilabel(app.UIUpdateExp);
            app.FromExpNrSpinnerLabel.Text = 'From Exp. Nr.';
            app.FromSpinner = uispinner(app.UIUpdateExp);
            app.FromSpinner.Limits = [1 Inf];
            app.FromSpinner.Value = 1;
            app.ToSpinnerLabel = uilabel(app.UIUpdateExp);
            app.ToSpinnerLabel.Text = 'To';
            app.ToSpinner = uispinner(app.UIUpdateExp);
            app.ToSpinner.Limits = [1 Inf];
            app.ToSpinner.Value = 1;
            app.RecZCheckBox = uicheckbox(app.UIUpdateExp);
            app.RecZCheckBox.Text = 'Update recording file status';
            % Show the figure after all components are created
            app.UIUpdateExp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = updateExpInfo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIUpdateExp)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIUpdateExp)
        end
    end
end