classdef updateExpInfo2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        CancelButton           matlab.ui.control.Button
        DoneButton             matlab.ui.control.Button
        ToSpinner              matlab.ui.control.Spinner
        ToSpinnerLabel         matlab.ui.control.Label
        FromSpinner            matlab.ui.control.Spinner
        FromExpNrSpinnerLabel  matlab.ui.control.Label
        DataTable              matlab.ui.control.Table
    end

    
    properties (Access = private)
        mainGui % Description
        nrExp
        %returnType %analyzer or sql update 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, nrExp, showTable)


            app.mainGui=mainapp;
            app.nrExp=nrExp;
            %app.returnType=type;
            
            app.FromSpinner.Limits=[1,app.nrExp];
            app.ToSpinner.Limits=[1,app.nrExp];
            app.ToSpinner.Value=app.nrExp;

            app.DataTable.Data=showTable;
            app.DataTable.ColumnName=showTable.Properties.VariableNames;
        end

        % Callback function
        function DoneButtonPushed(app, event)
            outTable=app.DataTable.Data;
            getExpInfo2(app.mainGui,outTable);
                     
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataTable = uitable(app.UIFigure);
            app.DataTable.ColumnName = {'{}'};
            app.DataTable.RowName = {};
            app.FromExpNrSpinnerLabel = uilabel(app.UIFigure);
            app.FromExpNrSpinnerLabel.Text = 'From Exp. Nr.';
            app.FromSpinner = uispinner(app.UIFigure);
            app.FromSpinner.Limits = [1 Inf];
            app.FromSpinner.Value = 1;
            app.ToSpinnerLabel = uilabel(app.UIFigure);
            app.ToSpinnerLabel.Text = 'To';
            app.ToSpinner = uispinner(app.UIFigure);
            app.ToSpinner.Limits = [1 Inf];
            app.ToSpinner.Value = 1;
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = updateExpInfo2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end